classdef CHESS_UI_canvas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProjectManagementMenu         matlab.ui.container.Menu
        CreateNewProjectMenu          matlab.ui.container.Menu
        ImportExportProjectMenu       matlab.ui.container.Menu
        ImportProjectMenu             matlab.ui.container.Menu
        ExportProjectMenu             matlab.ui.container.Menu
        AddProjectElementMenu         matlab.ui.container.Menu
        PSEMenu                       matlab.ui.container.Menu
        LeveeMenu                     matlab.ui.container.Menu
        RubbleMoundMenu               matlab.ui.container.Menu
        FloodwallMenu                 matlab.ui.container.Menu
        LowCrestedBreakwaterMenu      matlab.ui.container.Menu
        AlternativeMenu               matlab.ui.container.Menu
        TidesMenu                     matlab.ui.container.Menu
        RemoveProjectElementMenu      matlab.ui.container.Menu
        RemovePSEMenu                 matlab.ui.container.Menu
        RemoveAlternativeMenu         matlab.ui.container.Menu
        RemoveTidesMenu               matlab.ui.container.Menu
        RemoveAlternativeSimulationMenu  matlab.ui.container.Menu
        StormDatabaseManagementMenu   matlab.ui.container.Menu
        AddStormDatabaseMenu          matlab.ui.container.Menu
        ImportCHSStudyHDF5Menu        matlab.ui.container.Menu
        RemoveStormDatabaseMenu       matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ParentPanel                   matlab.ui.container.Panel
        MasterGrid                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        PSEViewerTab                  matlab.ui.container.Tab
        PSEViewerGrid                 matlab.ui.container.GridLayout
        PSETable                      matlab.ui.control.Table
        PSEButtonGrid                 matlab.ui.container.GridLayout
        ExtrudeFigureButton           matlab.ui.control.Button
        ExtrudeTableButton            matlab.ui.control.Button
        PSEAxes                       matlab.ui.control.UIAxes
        StormDBViewerTab              matlab.ui.container.Tab
        StormDBViewerGrid             matlab.ui.container.GridLayout
        StormDBViewerSplitGrid        matlab.ui.container.GridLayout
        StormDBTable                  matlab.ui.control.Table
        Panel                         matlab.ui.container.Panel
        StormDBViewerNavigationPanelGrid  matlab.ui.container.GridLayout
        StormDBDropDown               matlab.ui.control.DropDown
        StormDBDropDownLabel          matlab.ui.control.Label
        StormTypeDropDown             matlab.ui.control.DropDown
        StormTypeDropDownLabel        matlab.ui.control.Label
        DataTypeDropDown              matlab.ui.control.DropDown
        DataTypeDropDownLabel         matlab.ui.control.Label
        MatchTypeDropDown             matlab.ui.control.DropDown
        MatchTypeDropDownLabel        matlab.ui.control.Label
        FieldToPlotDropDown           matlab.ui.control.DropDown
        FieldToPlotLabel              matlab.ui.control.Label
        StormIDDropDown               matlab.ui.control.DropDown
        StormIDDropDownLabel          matlab.ui.control.Label
        StormDBViewerButtonGrid       matlab.ui.container.GridLayout
        ExtrudeFigureButton_2         matlab.ui.control.Button
        ExtrudeTableButton_2          matlab.ui.control.Button
        StormDBAxes                   matlab.ui.control.UIAxes
        CHESSRunTab                   matlab.ui.container.Tab
        RunViewerGrid                 matlab.ui.container.GridLayout
        GridLayout3_7                 matlab.ui.container.GridLayout
        simStatusBar                  matlab.ui.control.TextArea
        RunSimulationButton           matlab.ui.control.Button
        GridLayout3_6                 matlab.ui.container.GridLayout
        DataTypesTree                 matlab.ui.container.CheckBoxTree
        ResponsesTree                 matlab.ui.container.CheckBoxTree
        GridLayout3_5                 matlab.ui.container.GridLayout
        DataTypesToProcessLabel       matlab.ui.control.Label
        PSEResponsesLabel             matlab.ui.control.Label
        WorkflowInputsTable           matlab.ui.control.Table
        GridLayout3_4                 matlab.ui.container.GridLayout
        WorkflowDropDown              matlab.ui.control.DropDown
        DefineCHESSWorkflowLabel      matlab.ui.control.Label
        GridLayout3_3                 matlab.ui.container.GridLayout
        TidesFileDropDown             matlab.ui.control.DropDown
        TideFileDropDownLabel         matlab.ui.control.Label
        GridLayout3_2                 matlab.ui.container.GridLayout
        SeaLevelChangeEditField       matlab.ui.control.NumericEditField
        SeaLevelChangeEditFieldLabel  matlab.ui.control.Label
        StormTypeDropDown_2           matlab.ui.control.DropDown
        StormTypeDropDown_2Label_2    matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        StormDBDropDown_run           matlab.ui.control.DropDown
        StormDBDropDown_2Label        matlab.ui.control.Label
        ApplyDepthLimitationCheckBox  matlab.ui.control.CheckBox
        RunSimulationLabel            matlab.ui.control.Label
        DefineProjectResponsesDataTypesToComputeLabel  matlab.ui.control.Label
        DefineProjectForcingLabel_2   matlab.ui.control.Label
        ResultsViewerTab              matlab.ui.container.Tab
        StormDBViewerGrid_2           matlab.ui.container.GridLayout
        StormDBViewerSplitGrid_2      matlab.ui.container.GridLayout
        PSETable_3                    matlab.ui.control.Table
        Panel_2                       matlab.ui.container.Panel
        StormDBViewerNavigationPanelGrid_2  matlab.ui.container.GridLayout
        StormIDDropDown_results       matlab.ui.control.DropDown
        StormIDDropDown_2Label        matlab.ui.control.Label
        FieldToPlotDropDown_results   matlab.ui.control.DropDown
        FieldToPlotDropDown_2Label    matlab.ui.control.Label
        MatchTypeDropDown_results     matlab.ui.control.DropDown
        MatchTypeDropDown_2Label      matlab.ui.control.Label
        DataTypeDropDown_results      matlab.ui.control.DropDown
        DataTypeDropDown_2Label       matlab.ui.control.Label
        StormTypeDropDown_results     matlab.ui.control.DropDown
        StormTypeDropDown_2Label      matlab.ui.control.Label
        PlotTypeDropDown              matlab.ui.control.DropDown
        PlotTypeDropDownLabel         matlab.ui.control.Label
        StormDBViewerButtonGrid_2     matlab.ui.container.GridLayout
        Button_6                      matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        PSEAxes_3                     matlab.ui.control.UIAxes
        ProjectPanelGrid              matlab.ui.container.GridLayout
        ConstantsTable                matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        ConstantsLabel                matlab.ui.control.Label
        ProjectPanel                  matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        ProjectPathEditField          matlab.ui.control.EditField
        ProjectPathEditFieldLabel     matlab.ui.control.Label
        AlternativeDropDown           matlab.ui.control.DropDown
        AlternativeDropDownLabel      matlab.ui.control.Label
        PSEDropDown                   matlab.ui.control.DropDown
        PSEDropDownLabel              matlab.ui.control.Label
        ProjectDropDown               matlab.ui.control.DropDown
        ProjectLabel                  matlab.ui.control.Label
        ProjectNavigationLabel        matlab.ui.control.Label
        ProjectTree                   matlab.ui.container.Tree
        ProjectTreeLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        chess_projects % Loaded CHESS projects
        wProj % Active project Handle
        proj_indx
        pse_indx
        alt_indx
        auxLib
        simState % Description
        tableState % Description
        stormCache % [NEW] Loaded storm Data Keyed By File (containers.Map)
        simContext % [NEW] Project|PSE|Alternative Last Shown On CHESS Run Tab
        resultsState % [NEW] Loaded Results (Resp, config, structure, project_forcing, storm DB) For plot_project_responses
        resultsPlotOptions % [NEW] Results Plot Options Per Plot Type (Right-Click "Plot Options...", Session Only)
        resultsMenu % [NEW] Results Axes Right-Click Menu (Created In startupFcn)
        resultsPopout % [NEW] Classic Figure Reused For Multi-Panel Results Plots
        resultsTablePopout % DET Response Table Pop-Out (uifigure From det_methods.response_table)
        simKey % [NEW] Saved Simulation Key (Config File Of Alternative + Workflow + Run Tab Storm DB) Last Resolved By sync_saved_sim
        declinedSimKey = '' % Saved Simulation Key Whose Load Was Declined ("Keep current inputs"): Results Tab Kept Cleared While sim_key() Equals It
        oldDbWarned = {} % Storm DB Files (output_paths.storm_db) Already Alerted As Older CHESS Version (Once Per Session)
        plotFontSize = 12 % Base Font Of Every GUI Plot, Tab And Pop-Out (Library Rule: Title +2, Labels Base, Ticks -2)
        resultsMirror % Panel Over PSEAxes_3 (Same Grid Cell) Showing A Multi-Panel Results Plot When The Cell Is Big Enough (Created In startupFcn)
        resultsMirrorFits = [] % Fit State Of The Shown Multi-Panel Results Plot ([] If None): The Tab Resize Redraws Only When It Flips
        runActive = false
    end

    methods (Access = private)
        function append_project(app, wProj)
            % Append SS Project & Make It Active
            app.chess_projects = [app.chess_projects; wProj];
            app.set_active_selection(wProj.name, '', '');
            % Keep Per-Alternative Fields Sized
            app.project_field_ini();
            % Refresh UI
            app.refresh_active_view();
        end

        function backed = backup_sim_files(app, bk, level)
            % [NEW] Back Up The Slot Before The Run Touches It (Before apply_hotstart_level Deletes Anything), By Hotstart Level:
            %   Config: Copied. Forcing: Moved (cold | resample | reuse_sampling: Rebuilt By The Run); 'full' Reuses It As Is
            %   (Nothing Re-Saves It), No Backup. Seed: Moved (cold | resample: Sampled Again), Copied (reuse_sampling: Read,
            %   Then Saved Again); 'full': No Backup. backed: Files This Run Backed Up (existed = false: Not There Before The
            %   Run, Its File Is Deleted On Failure). A Failure While Backing Up Restores What Was Done And Rethrows
            act = struct('config', 'copy', 'forcing', 'move', 'seed', 'move');
            switch level
                case 'full'
                    act.forcing = '';
                    act.seed = '';
                case 'reuse_sampling'
                    act.seed = 'copy';
            end
            backed = struct('name', {}, 'file', {}, 'prev', {}, 'existed', {});
            try
                for kk = 1:numel(bk)
                    entry = bk(kk);
                    if isempty(act.(entry.name))
                        continue
                    end
                    entry.existed = isfile(entry.file);
                    if entry.existed && strcmp(act.(entry.name), 'copy')
                        copyfile(entry.file, entry.prev, 'f');
                    elseif entry.existed
                        movefile(entry.file, entry.prev, 'f');
                    end
                    backed(end+1) = entry; %#ok<AGROW>
                end
            catch ME
                app.restore_sim_files(backed);
                rethrow(ME);
            end
        end

        function [config, pseObj] = create_resp_config(app, pseObj, dbObj, for_run, plan)
            % Run Step: Build config From Run Tab + Project + Storm DB, Then Apply Library Run Rules
            %   for_run = false: Input Comparison Only (No Hotstart Actions, Nothing Saved)
            %   plan: Run Start (run_hotstart_plan): plan.level 'cold' (Default) | 'full' | 'reuse_sampling', plan.old: Saved Run's config
            if nargin < 4
                for_run = true;
            end
            if nargin < 5
                plan = struct('level', 'cold', 'old', []);
            end
            [pse_name, alt_name] = app.get_active_names();
            [~, ~, ~, ~, out_files] = app.get_config_path();
            % Library Template Defaults For storm_duration (Fresh Table) And Workflow Sections (Library Functions Read Them Regardless Of Workflow)
            tpl = app.wProj.get_library_template(pseObj.pse_type);
            wf_rows = [tpl.forcing(strcmp(tpl.forcing(:, 1), 'storm_duration'), :); tpl.mcs; tpl.csr; tpl.pros];
            for rr = 1:size(wf_rows, 1)
                config.(wf_rows{rr, 1}) = wf_rows{rr, 4};
            end
            % Every Library Response Switch Defaults To Off (Union Of All Templates)
            types = {'Levee', 'Floodwall', 'Rubble Mound', 'Low Crested Breakwater'};
            for tt = 1:length(types)
                tpl_t = app.wProj.get_library_template(types{tt});
                for rr = 1:size(tpl_t.responses, 1)
                    config.(tpl_t.responses{rr, 1}) = 0;
                end
            end
            % Response Parameter Defaults (e.g. compute_damaging_depth_Ks)
            is_param = ~startsWith(tpl.responses(:, 2), 'Comput');
            for rr = find(is_param)'
                config.(tpl.responses{rr, 1}) = tpl.responses{rr, 4};
            end
            % Model Switches
            config.workflow = app.WorkflowDropDown.Value;  % Workflow
            config.storm_sampling = app.StormTypeDropDown_2.Value; % Storm Types To Process
            % Data Types (Tree NodeData Holds Library Symbols)
            dt_checked = app.get_checked_symbols(app.DataTypesTree);
            config.use_peaks = +any(ismember({'peaks_default','create_wlp','create_whp'}, dt_checked));
            config.use_timeseries = +ismember('use_timeseries', dt_checked);
            config.create_wlp = +ismember('create_wlp', dt_checked);
            config.create_whp = +ismember('create_whp', dt_checked);
            % Project Forcing Adjustments
            config.swl_slr = app.SeaLevelChangeEditField.Value; % SLR
            config.tide_file = app.TidesFileDropDown.Value; % Tides
            config.apply_random_tides = +~strcmp(config.tide_file, 'None');
            config.apply_depth_limitation = +app.ApplyDepthLimitationCheckBox.Value; % Depth Limitation
            config.load_project_forcing = 0; % GUI Builds Project Forcing Itself
            config.ref_case_name = 'none';
            % Responses (Tree NodeData Holds Library Symbols)
            resp_checked = app.get_checked_symbols(app.ResponsesTree);
            for rr = 1:length(resp_checked)
                config.(resp_checked{rr}) = 1;
            end
            % Inputs Of Every Workflow (Visible And Hidden Rows): Config Records The Full Input State
            app.store_table_values();
            input_fields = fieldnames(app.tableState);
            for rr = 1:length(input_fields)
                config.(input_fields{rr}) = +app.tableState.(input_fields{rr});
            end
            % Storm DB Fields
            config.region = dbObj.study;
            config.sp_ID = '';
            config.sp_ID_wave = '';
            config.storm_db = app.StormDBDropDown_run.Value;
            % Project Pathing
            config.outfolder = app.wProj.path;
            config.project_name = app.wProj.name;
            config.struc_id = pse_name;
            config.case_name = alt_name;
            % Project CLs ([CL1 CL2 ...], Library Sorts & Formats)
            cls = app.wProj.get_project_field('CLs', '', '', 'proj');
            cls(cls==0) = [];
            config.project_CLs = ['[' strjoin(arrayfun(@num2str, cls, 'un', false), ' ') ']'];
            config.project_datum = app.wProj.vdatum;
            % Constants
            config.gravity_constant = app.pull_input_table_value(app.ConstantsTable.Data, 'Gravity Constant');
            % Structure -> Config Bridge (Library Reads These From config, e.g. Hotstart Dependencies)
            geo_fields = fieldnames(pseObj);
            for rr = 1:length(geo_fields)
                config.(geo_fields{rr}) = pseObj.(geo_fields{rr});
            end
            % Storm DB Details (db_info, Library storm_db_info): Nyrs_XC/Nstm_XC, TC Bias Correction, Modeling Bias & Uncertainty,
            % storm_db_build_date (Re-Imported Storm DB: Library Hotstart Dependency, 'resample'), Offshore Elevation (Study
            % Grid Value Wins Over The PSE Geometry Value Copied Above). After The Structure Bridge So The Grid Value Is Kept
            config = storm_db_info('apply', dbObj.get_db_info(), config);
            % Library Run Rules (Restrictions, Dependencies, CLs, Uncertainties, out_files)
            config = finalize_config(config);
            % LCS Convergence: The GUI Asks Before The Run (lcs_convergence_confirm); The Library Must Never Prompt In The Command Window
            config.lcs_convergence_prompt = 0;
            % GUI File Names (Per Storm DB)
            config.out_files = out_files;
            if ~for_run
                return
            end
            % Hotstart: Library Level Actions (apply_hotstart_level). Not resolve_hotstart_state: The GUI Takes The Level From
            % The Run Dialog (run_hotstart_plan). Coldstart: Cached Forcing And LCS Seed Deleted (Already Moved To *_prev By backup_sim_files)
            if strcmp(plan.level, 'cold')
                for ff = {out_files.project_forcing, lcs_sampling_seed_file(out_files.project_forcing)}
                    if isfile(ff{1})
                        delete(ff{1});
                    end
                end
                config.u_engine = 0;
                config.f_adjust = 0;
                config.hotstart_level = 'cold';
            else
                config = apply_hotstart_level(config, plan.old, plan.level, config.workflow == 3);
            end
            % Save Config (Library Drivers Append To This File During The Run). RunSimulationButtonPushed Backs Up The Previous
            % One First (backup_sim_files) And Deletes The Backup Once The Results Are Saved, Or Restores It If The Run Fails
            out_pth = fileparts(out_files.config);
            if ~exist(out_pth, 'dir')
                mkdir(out_pth);
            end
            save(out_files.config, 'config', '-v7.3');
        end

        function elev = db_offshore_elevation(app, dbObj)
            % [NEW] Offshore Elevation Of A Storm DB: db_info Study Grid Value (Library storm_db_bias_uncertainty). [] When
            % The Storm DB Has None (Custom Modeling, No CHS_Dependencies, Grid Without Values): Inputs Table Value Is Used
            elev = [];
            if isstruct(dbObj.db_info) && isfield(dbObj.db_info, 'offshore_elevation') && ~isempty(dbObj.db_info.offshore_elevation)
                elev = dbObj.db_info.offshore_elevation;
            end
        end

        function fields = det_field_list(app, data)
            % [NEW] Deterministic Results: Plottable Fields (Forcing First, Then Responses), Same Order As det_methods.available_fields
            forcing = {'SWL', 'Hm0', 'Hm0_DL', 'Tp', 'h', 'Rc'};
            names = setdiff(fieldnames(data.Max), {'storm_id', 'time'}, 'stable')';
            fields = [forcing(ismember(forcing, names)), setdiff(names, forcing, 'stable')];
        end

        function extract_project_zip(app, zip_file)
            % [NEW] Import An Exported Project Archive: Exactly One Top-Level Folder <top>/ Holding <top>/CHESS_project_<top>.mat
            % (Export Project Layout), No Top-Level Files. Unzipped Into A Chosen Folder, Never Over An Existing <dest>\<top>,
            % Then Imported From There (import_project_mat). The Extracted Folder Is Kept If The Import Fails Afterwards
            try
                entries = zip_entries(zip_file);
            catch
                app.auxLib.error_dlgbox('Could not read the selected file as a zip archive.');
                return
            end
            has_sep = contains(entries, '/');
            tops = unique(extractBefore(entries(has_sep), '/'));
            top = '';
            if all(has_sep) && isscalar(tops)
                top = tops{1};
            end
            if isempty(top) || ~any(strcmp(entries, [top '/CHESS_project_' top '.mat']))
                app.auxLib.error_dlgbox('Not a CHESS project archive: it must hold one project folder <name> with <name>/CHESS_project_<name>.mat.');
                return
            end
            if ~isempty(app.chess_projects) && any(strcmp(top, {app.chess_projects.name}))
                app.auxLib.error_dlgbox(['Project "' top '" is already loaded.']);
                return
            end
            dest = uigetdir('', ['Extract Project "' top '" Into Folder']);
            if isequal(dest, 0)
                return
            end
            if exist(fullfile(dest, top), 'file')
                app.auxLib.error_dlgbox(['"' fullfile(dest, top) '" already exists. Choose another folder.']);
                return
            end
            try
                unzip(zip_file, dest);
            catch ME
                app.auxLib.error_dlgbox(['Could not extract the archive: ' ME.message]);
                return
            end
            app.import_project_mat(fullfile(dest, top, ['CHESS_project_' top '.mat']));

            function names = zip_entries(file)
                % Entry Names Without Extracting ('\' Normalised To '/'); Directory Entries Are Skipped
                zf = java.util.zip.ZipFile(file);
                closer = onCleanup(@() zf.close());
                names = {};
                en = zf.entries();
                while en.hasMoreElements()
                    nm = strrep(char(en.nextElement().getName()), '\', '/');
                    if ~endsWith(nm, '/')
                        names{end+1} = nm; %#ok<AGROW>
                    end
                end
            end
        end

        function [pse_name, alt_name] = get_active_names(app)
            % [NEW] Active PSE/Alternative Names ('' When None)
            pse_name = '';
            alt_name = '';
            if isempty(app.wProj) || isempty(app.pse_indx)
                return
            end
            pse_name = app.wProj.pse_list{app.pse_indx};
            if ~isempty(app.alt_indx)
                alt_name = app.wProj.alternative_list{app.pse_indx}{app.alt_indx};
            end
        end

        function symbols = get_checked_symbols(app, tree)
            % [NEW] Library Symbols (NodeData) Of Checked Nodes
            if isempty(tree.CheckedNodes)
                symbols = {};
            else
                symbols = {tree.CheckedNodes.NodeData};
            end
        end

        function [out_path, out_dir, wflow_folder, wflow_name, out_files] = get_config_path(app)
            % Run Folder & File Names From The Library's chess_paths (Same Layout As The Library):
            % <project>\PSE\<pse>\<alt>\<workflow folder>\<db>_<workflow>_*, Per Storm DB So Runs With Different DBs Do Not Overwrite Each Other
            [pse_name, alt_name] = app.get_active_names();
            paths = chess_paths(app.wProj.path, app.wProj.name, pse_name, alt_name,...
                app.WorkflowDropDown.Value, app.StormDBDropDown_run.Value);
            out_dir = paths.wf_dir;
            wflow_folder = paths.wf_folder;
            wflow_name = paths.wflow_name;
            out_files = paths.out_files;
            out_path = out_files.config;
        end

        function [proj_node, pse_node, alt_node, active_node, active_type] = get_node_family(app)
            % Get Active Node (Tree Nodes Carry Their Level In NodeData)
            active_node = app.ProjectTree.SelectedNodes;
            active_type = active_node.NodeData.type;
            pse_node = [];
            alt_node = [];
            switch active_type
                case 'proj' % Active Node Is Project (First PSE/Alternative If Any)
                    proj_node = active_node;
                    if ~isempty(proj_node.Children)
                        pse_node = proj_node.Children(1);
                    end
                    if ~isempty(pse_node) && ~isempty(pse_node.Children)
                        alt_node = pse_node.Children(1);
                    end
                case 'pse' % Active Node Is PSE (First Alternative)
                    pse_node = active_node;
                    proj_node = pse_node.Parent;
                    if ~isempty(pse_node.Children)
                        alt_node = pse_node.Children(1);
                    end
                case 'alt' % Active Node Is Alternative
                    alt_node = active_node;
                    pse_node = alt_node.Parent;
                    proj_node = pse_node.Parent;
            end
        end

        function opts = get_results_options(app, key)
            % [NEW] Plot Options (Right-Click) For A Plot Type: Session Values, Else Library Defaults
            opts = struct('bin_edges', [], 'cb_lim', [], 'grid_on', false, 'cb_log', false, 'c_field', '');
            if isstruct(app.resultsPlotOptions) && isfield(app.resultsPlotOptions, key)
                opts = app.resultsPlotOptions.(key);
            end
        end

        function get_sim_state(app)
            % Snapshot Of Run Tab Controls (Component Name, Value). Inputs Table Values Live In tableState
            sim_tab = [{'StormDBDropDown_run', app.StormDBDropDown_run.Value};...
                {'ApplyDepthLimitationCheckBox', app.ApplyDepthLimitationCheckBox.Value};...
                {'StormTypeDropDown_2',app.StormTypeDropDown_2.Value};...
                {'TidesFileDropDown', app.TidesFileDropDown.Value};...
                {'WorkflowDropDown', app.WorkflowDropDown.Value};...
                {'SeaLevelChangeEditField', app.SeaLevelChangeEditField.Value};...
                {'ResponsesTree', {app.get_checked_symbols(app.ResponsesTree)}};...
                {'DataTypesTree', {app.get_checked_symbols(app.DataTypesTree)}};...
                {'ResponseSymbols', {app.get_tree_symbols(app.ResponsesTree)}};... % Tree Nodes Shown (New Context: Template Defaults Only For Others)
                {'DataTypeSymbols', {app.get_tree_symbols(app.DataTypesTree)}}];
            % Unwrap Cell-Wrapped Entries
            sim_tab(7:10, 2) = cellfun(@(x) x{1}, sim_tab(7:10, 2), 'un', false);
            app.simState = sim_tab;
        end

        function nodes = get_tree_nodes(app, tree)
            % [NEW] All Tree Nodes (Two Levels) As A Column
            nodes = tree.Children(:);
            for kk = 1:length(tree.Children)
                nodes = [nodes; tree.Children(kk).Children(:)]; %#ok<AGROW>
            end
        end

        function symbols = get_tree_symbols(app, tree)
            % [NEW] Library Symbols (NodeData) Of All Nodes
            nodes = app.get_tree_nodes(tree);
            if isempty(nodes)
                symbols = {};
            else
                symbols = {nodes.NodeData};
            end
        end

        function tf = has_active_alt(app)
            % [NEW] True When Active Project Has An Active PSE And Alternative
            tf = ~isempty(app.wProj) && ~isempty(app.pse_indx) && ~isempty(app.alt_indx);
        end

        function hide_results_mirror(app)
            % [NEW] Results Tab Back To Its Axes: Clear And Hide The Multi-Panel Mirror Panel
            delete(app.resultsMirror.Children);
            app.resultsMirror.Visible = 'off';
            app.PSEAxes_3.Visible = 'on';
        end

        function import_project_mat(app, mat_file)
            % [NEW] Import A Project From Its Project File <root>\<name>\CHESS_project_<name>.mat. Project Folder Not Where It
            % Was Saved (Moved, Copied, Extracted Archive): Stored Paths Relocated To <root>\<name> (Before append_project:
            % refresh_active_view Reads The Storm DB Paths), Then Saved There
            S = load(mat_file);
            if ~isfield(S, 'chess_project') || ~isa(S.chess_project, 'chessProject')
                app.auxLib.error_dlgbox('Please select a valid "chessProject" class object.');
                return
            end
            wProj = S.chess_project;
            if ~isempty(app.chess_projects) && any(strcmp(wProj.name, {app.chess_projects.name}))
                app.auxLib.error_dlgbox(['Project "' wProj.name '" is already loaded.']);
                return
            end
            [new_root, folder_name] = fileparts(fileparts(mat_file));
            if ~strcmp(folder_name, wProj.name)
                app.auxLib.error_dlgbox(['The project file must be inside its project folder "' wProj.name '" (found in "' folder_name '").']);
                return
            end
            if ~chessProject.same_path(new_root, wProj.path)
                wProj.relocate(new_root);
            end
            % A CHS_Dependencies Folder Bundled In The Project (Export Project) Is Always Used
            bundle_dir = fullfile(new_root, wProj.name, 'CHS_Dependencies');
            if isfolder(bundle_dir)
                wProj.chs_dependencies = bundle_dir;
            end
            app.append_project(wProj);
            app.save_project();
        end

        function proceed = lcs_convergence_confirm(app, pseObj, dbObj, prob_mass)
            % [NEW] LCS Convergence Dialog, Replacing The Library's Command-Window Prompt. Same Rule As chess_lcs
            % (lcs_reference_reuse): Effective Mode 0, Storm Types Not Reused From The Reference Case. Called Only When
            % Life Cycles Are Sampled (Coldstart; After The Run Dialog: A Hotstart Reuses The Cached Forcing Or Sampling).
            % Not Converged: "Set mcs_nLC To <nLC_req> And Run" | "Run Anyway" | "Cancel". Returns false On Cancel (Or Dialog Closed).
            proceed = true;
            config = app.create_resp_config(pseObj, dbObj, false);
            if config.workflow ~= 3
                return
            end
            reuse = lcs_reference_reuse(config);
            if isempty(reuse.check_types)
                return
            end
            checks = lcs_convergence_checks(config, prob_mass, reuse.check_types, true);
            checks = checks(~[checks.converged]);
            if isempty(checks)
                return
            end
            n_txt = @(n) aux_methods.tern(isinf(n), sprintf('> %s', regexprep(num2str(checks(1).nLC_cap), '(\d)(?=(\d{3})+$)', '$1,')), sprintf('%d', n));
            msg = sprintf('mcs_nLC = %d is below the number of life cycles needed for convergence (tol %g, confidence %g):',...
                config.mcs_nLC, config.lcs_convergence_tol, config.lcs_convergence_confidence);
            for cc = 1:numel(checks)
                msg = sprintf('%s\n%s: lambda x N = %.2f storms per life cycle; needed: mean %d, percentiles %s -> %s.', msg,...
                    checks(cc).stm_type, checks(cc).lamN, checks(cc).nLC_mean, n_txt(checks(cc).nLC_pct), n_txt(checks(cc).nLC_req));
            end
            n_req = max([checks.nLC_req]);
            options = {'Run anyway', 'Cancel'};
            if isfinite(n_req)
                options = [{sprintf('Set mcs_nLC to %d and run', n_req)}, options];
            end
            app.auxLib.choice_dlgbox(msg, options, 'LCS Convergence Check', [620, 150 + 20*numel(checks)]);
            answer = app.auxLib.dialog_answer;
            if isempty(answer) || strcmp(answer, 'Cancel')
                proceed = false;
            elseif startsWith(answer, 'Set mcs_nLC')
                % Write The Visible Table Row Too: set_input_table First Stores The Visible Table Into tableState
                row = strcmp(app.WorkflowInputsTable.Data(:, 1), 'mcs_nLC');
                app.WorkflowInputsTable.Data(row, 3) = {n_req};
                app.tableState.mcs_nLC = n_req;
                app.set_input_table(); % Inputs Table Shows (And The Run Reads) The New mcs_nLC
            end
        end

        function chs_data = load_chs_data(app, dbObj)
            % [NEW] Cached Load Of Raw CHS_Data (Storm DB "_raw_files.mat"); [] If Unavailable
            chs_data = [];
            if ~isfield(dbObj.output_paths, 'chs_data') || ~isfile(dbObj.output_paths.chs_data)
                return
            end
            key = dbObj.output_paths.chs_data;
            if isempty(app.stormCache)
                app.stormCache = containers.Map();
            end
            if ~isKey(app.stormCache, key)
                S = load(key, 'CHS_Data');
                app.stormCache(key) = S.CHS_Data;
            end
            chs_data = app.stormCache(key);
        end

        function load_saved_sim(app)
            % [NEW] Restore The Saved Simulation Of The Current Key: Run Tab Inputs (Saved Config) And Results
            app.simKey = app.sim_key(); % Resolved: populateResultsTab Loads It
            app.declinedSimKey = '';
            app.set_sim_state(2, app.get_config_path());
            app.populate_pse_table();
            app.populate_constants_table();
            app.set_input_table();
            app.populateResultsTab();
        end

        function storm = load_storm(app, dbObj)
            % [NEW] Cached Load Of Storm DB "storm" Variable
            key = dbObj.output_paths.storm_db;
            if isempty(app.stormCache)
                app.stormCache = containers.Map();
            end
            if ~isKey(app.stormCache, key)
                S = load(key, 'storm');
                app.stormCache(key) = S.storm;
            end
            storm = app.stormCache(key);
        end

        function msg = loaded_sim_status(app)
            % [NEW] Status Bar Text Of The Saved Simulation Shown
            [~, ~, ~, wflow_name] = app.get_config_path();
            [~, ~, run_date] = app.saved_sim();
            msg = sprintf('Saved simulation loaded (%s_%s, run %s).', app.StormDBDropDown_run.Value, wflow_name, run_date);
        end

        function config = localize_saved_config(app, config)
            % [NEW] Saved Run config Of A Moved Project (chessProject.relocate): A tide_file Under The Run's Project Folder
            % (<outfolder>\<project_name>) Points Into The Active Project Folder. 'None' And Paths Elsewhere Unchanged
            config.tide_file = chessProject.relocate_path(config.tide_file,...
                fullfile(config.outfolder, config.project_name), fullfile(app.wProj.path, app.wProj.name));
        end

        function open_results_popout(app)
            % [NEW] Multi-Panel Results Plots: Draw In One Reused Classic Figure
            % (DET Response Table Builds Its Own uifigure: No Classic Figure Needed)
            row = app.results_plot_row();
            if strcmp(app.StormTypeDropDown_results.Value, '-') % No Storm Type For This Plot: No Empty Pop-Out
                return
            end
            if ~isempty(row) && strcmp(row.key, 'det_table')
                app.plot_project_responses([], app.plotFontSize);
                return
            end
            if isempty(app.resultsPopout) || ~isvalid(app.resultsPopout)
                app.resultsPopout = figure('Name', 'CHESS Results');
            else
                clf(app.resultsPopout);
                figure(app.resultsPopout);
            end
            app.plot_project_responses(app.resultsPopout, app.plotFontSize);
        end

        function vals = parse_numeric_list(app, txt)
            % [NEW] Numbers From Text Without eval: "0 0.5 1", "0, 0.5, 1", "[0 1]" Or "start:step:end" ([] If Blank, NaN If Invalid)
            txt = strtrim(regexprep(char(txt), '[\[\]]', ''));
            vals = [];
            if isempty(txt)
                return
            end
            if contains(txt, ':')
                parts = str2double(strsplit(txt, ':'));
                if any(isnan(parts)) || ~ismember(numel(parts), [2 3])
                    vals = NaN;
                elseif numel(parts) == 2
                    vals = parts(1):parts(2);
                else
                    vals = parts(1):parts(2):parts(3);
                end
                if isempty(vals)
                    vals = NaN;
                end
            else
                vals = str2double(regexp(txt, '[,;\s]+', 'split'));
            end
        end

        function plot_project_responses(app, target, base_fnt)
            % [NEW] Results Viewer: Draw The Selected Plot Type With Its CHESS-Library Method.
            %   plot_project_responses(app)                   -> Results Tab Axes + Results Table (Multi-Panel Plots: Drawn
            %                                                    In The Tab Mirror Panel If The Cell Is Big Enough, Else A Note)
            %   plot_project_responses(app, target, base_fnt) -> Pop-Out (Classic Figure Or Axes)
            tab_mode = nargin < 2;
            if tab_mode
                target = app.PSEAxes_3;
                base_fnt = app.plotFontSize;
            end
            state = app.resultsState;
            row = app.results_plot_row();
            if isempty(state) || isempty(row)
                return
            end
            no_storm_type = strcmp(app.StormTypeDropDown_results.Value, '-');
            if tab_mode
                app.reset_results_axes();
                app.resultsMirrorFits = [];
                app.PSETable_3.Data = {};
                app.PSETable_3.ColumnName = {};
                if no_storm_type
                    title(app.PSEAxes_3, 'No storm type with data for this plot');
                    return
                end
                if row.multi_panel % Also In The Pop-Out: Mirror In The Tab If It Fits, Else Grey Out The Tab Axes
                    app.resultsMirrorFits = app.results_mirror_fits(row);
                    if ~app.resultsMirrorFits
                        app.show_popout_note(row);
                        return
                    end
                    app.PSEAxes_3.Visible = 'off';
                    app.resultsMirror.Visible = 'on';
                    target = uigridlayout(app.resultsMirror, [1 1]);
                end
            elseif no_storm_type
                return
            end
            % Single-Panel Plots Need Axes, Multi-Panel Plots Take The Figure
            if ~row.multi_panel && isa(target, 'matlab.ui.Figure')
                target = axes('Parent', target);
            end
            ax = target;
            % Selection
            ds_query = struct('storm_type', app.StormTypeDropDown_results.Value,...
                'data_type', app.DataTypeDropDown_results.Value,...
                'match_type', app.MatchTypeDropDown_results.Value);
            field = app.FieldToPlotDropDown_results.Value;
            aux = app.StormIDDropDown_results.Value;
            lc_index = str2double(aux); % Life Cycle ('All' -> [])
            if isnan(lc_index)
                lc_index = [];
            end
            opts = app.get_results_options(row.key);
            % Plot Data Loaded On Demand
            config = state.config;
            storm = [];
            dsw = [];
            add_events = 0; % Hazard Curve Events Overlay Mode: 0 Off, 1 Run Sample, 2 Storm Values
            if strcmp(row.key, 'hc') && ~strcmp(ds_query.storm_type, 'CC') % CC: No Overlays
                add_events = max([0, find(strcmp(aux, {'Run Sample', 'Storm Values'}), 1)]);
            end
            if any(strcmp(row.key, {'event', 'scheme'}))
                storm = app.load_storm(state.dbObj);
                config.sp_ID = state.dbObj.get_savepoint_id(); % Title (GUI Run Configs Leave sp_ID Empty)
            end
            project_forcing = state.project_forcing;
            if add_events > 0 || strcmp(row.key, 'scheme') % Events Overlay / Sampling Scheme Need Project Forcing + TC Probability Masses
                [project_forcing, dsw] = app.results_forcing_and_dsw();
            end
            chessPlot = CHESSPlots(config, state.Resp, storm, project_forcing, dsw, state.structure, base_fnt, app.wProj.unitsys);
            try
                switch row.key
                    case 'hc' % PROS Hazard Curve (+ Table)
                        chessPlot.hc_plot(ax, ds_query, field, add_events);
                        if tab_mode
                            data = aux_methods.pull_data(ds_query, state.Resp);
                            data = data(strcmp({data.var}, field));
                            cls = arrayfun(@(x) ['CL' num2str(x)], data.CL, 'un', false);
                            cls(data.CL == 50) = {'BE'};
                            if state.config.pros_use_aep == 1
                                x_lbl = 'AEP';
                            else
                                x_lbl = 'AEF [1/yr]';
                            end
                            app.PSETable_3.ColumnName = [{x_lbl}, cls];
                            app.PSETable_3.Data = [data.x_table, data.y_table];
                        end
                    case 'det_scatter' % Deterministic: Response vs Forcing (X: Storm ID Dropdown, Y: Field, Color: Plot Options)
                        fields = app.det_field_list(aux_methods.pull_data(ds_query, state.Resp));
                        c_field = opts.c_field;
                        if isempty(c_field) || ~any(strcmp(fields, c_field))
                            c_field = fields{max([1, find(strcmp(fields, 'Hm0_DL'), 1)])};
                        end
                        chessPlot.response_vs_forcing_plot(ax, ds_query, aux, field, c_field);
                        if tab_mode % Table: The Plotted Values (Per Storm; Timeseries -> Storm Maxima)
                            mx = aux_methods.pull_data(ds_query, state.Resp).Max;
                            app.PSETable_3.ColumnName = {'Storm ID', aux, field, c_field};
                            app.PSETable_3.Data = [mx.storm_id(:), mx.(aux)(:), mx.(field)(:), mx.(c_field)(:)];
                        end
                    case 'det_table' % Deterministic: Response Table Pop-Out (uifigure, Replaces The Previous One)
                        if ~isempty(app.resultsTablePopout) && isvalid(app.resultsTablePopout)
                            delete(app.resultsTablePopout);
                        end
                        storm_id = str2double(aux);
                        if isnan(storm_id)
                            storm_id = [];
                        end
                        app.resultsTablePopout = chessPlot.response_table(ds_query, storm_id);
                        app.resultsTablePopout.Position(1:2) = app.UIFigure.Position(1:2) + (app.UIFigure.Position(3:4) - app.resultsTablePopout.Position(3:4))/2;
                    case 'det_compare' % Deterministic: Peaks Value vs Timeseries Peak (Same Storms, 1:1 Line)
                        chessPlot.peaks_vs_timeseries_plot(ax, ds_query, field);
                        if tab_mode
                            pk = aux_methods.pull_data(ds_query, state.Resp).Max;
                            q_ts = ds_query;
                            q_ts.data_type = 'Timeseries';
                            ts = aux_methods.pull_data(q_ts, state.Resp).Max;
                            [ids, ia, ib] = intersect(pk.storm_id, ts.storm_id);
                            app.PSETable_3.ColumnName = {'Storm ID', ['Peaks ' field], ['Timeseries ' field]};
                            app.PSETable_3.Data = [ids(:), pk.(field)(ia), ts.(field)(ib)];
                        end
                    case 'event' % Storm Event Viewer (Pop-Out): Forcing + Recomputed Response For One Event (Peaks: All)
                        event_id = 0;
                        if strcmp(ds_query.data_type, 'Timeseries')
                            event_id = str2double(aux);
                        end
                        chessPlot.storm_event_viewer(target, ds_query, event_id, field, 0);
                    case 'annual' % LCS Annual Response Distribution (+ Table Of Binned Values Per Sim Year)
                        [edges, labels] = app.results_bin_edges(chessPlot, ds_query, field, opts.bin_edges);
                        [~, bin_data] = chessPlot.annual_response_distribution(ax, ds_query, field, edges, string(aux),...
                            opts.cb_lim, opts.grid_on, opts.cb_log);
                        if tab_mode && isempty(bin_data) % No Finite Values: Library "No Data" Axes, Empty Table
                            app.PSETable_3.ColumnName = {'Sim Year'};
                            app.PSETable_3.Data = zeros(0, 1);
                        elseif tab_mode
                            app.PSETable_3.ColumnName = [{'Sim Year'}, labels];
                            app.PSETable_3.Data = [(1:size(bin_data, 1))', bin_data(:, 1:numel(labels))];
                        end
                    case 'armor' % Slope Armor Damage (S_* Fields: All Or One Life Cycle)
                        hold(ax, 'on');
                        chessPlot.slope_armor_damage_plot(ax, ds_query, field, lc_index);
                    case 'sampling' % Event Sampling Distribution (All Or One Life Cycle)
                        chessPlot.event_sampling_distribution(ax, ds_query, lc_index);
                    case 'damaging'
                        chessPlot.plot_mound_damaging_instances_distribution(ax, ds_query, field);
                    case 'submergence' % Field To Plot -> Normalize, Storm ID -> X Scale
                        chessPlot.plot_pse_submergance_distribution(ax, ds_query, strcmp(aux, 'Log'), strcmp(field, 'count'),...
                            opts.cb_log, opts.cb_lim);
                    case 'final'
                        chessPlot.plot_mound_final_damage_state(ax, ds_query, field);
                    case 'dashboard' % Mound Damage Dashboard (Pop-Out)
                        chessPlot.mound_damage_dashboard(target, ds_query, field, opts.bin_edges, opts.cb_lim);
                    case 'scheme' % LCS Storm Sampling Diagnostics (Pop-Out)
                        chessPlot.sampling_scheme_dashboard(target, ds_query);
                end
            catch me
                if tab_mode
                    app.reset_results_axes();
                    title(app.PSEAxes_3, 'Plot not available for this selection');
                end
                uialert(app.UIFigure, me.message, ['Results Plot: ' row.label], 'Icon', 'warning');
            end
            % Right-Click Plot Options On The Tab Plot (And The Mirrored Multi-Panel Axes)
            if tab_mode
                set(findobj([app.PSEAxes_3; app.resultsMirror.Children], '-property', 'ContextMenu'), 'ContextMenu', app.resultsMenu);
            end
        end

        function plot_storm_db(app, ax, base_fnt)
            % [NEW] Plot Storm DB Viewer Selection With CHESS-Library Plot Classes.
            %   plot_storm_db(app)               -> Storm DB Tab Axes + Storm DB Table
            %   plot_storm_db(app, ax, base_fnt) -> Given Axes Only (e.g. Pop-Out Figure)
            tab_mode = nargin < 2;
            if tab_mode
                ax = app.StormDBAxes;
                base_fnt = app.plotFontSize;
            end
            % Pull Storm DB (Cached). Storm DB Of An Older CHESS Version (No db_info): Alert, No Plot
            dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown.Value, '', 'db');
            if ~app.storm_db_info_ok(dbObj)
                cla(ax);
                legend(ax, 'off');
                if tab_mode
                    app.StormDBTable.Data = cell(0, 2);
                end
                return
            end
            storm = app.load_storm(dbObj);
            ds_query = struct('storm_type', app.StormTypeDropDown.Value,...
                'data_type', app.DataTypeDropDown.Value,...
                'match_type', app.MatchTypeDropDown.Value);
            % Library Plot Object (Storm Database Only: No PSE, Workflow Or Results)
            config = struct('project_datum', app.wProj.vdatum, 'apply_random_tides', 0,...
                'region', dbObj.study, 'sp_ID', dbObj.get_savepoint_id(), 'struc_id', '', 'case_name', '',...
                'r2p_u', 0, 'q_u', 0, 'dn50_u', 0, 'p1_u', 0,... % Axis Labels Only (response_library_headers)
                'chs_swl_u_a', 0, 'chs_swl_u_r', 0, 'chs_hm0_u_a', 0, 'chs_hm0_u_r', 0);
            % TC SWL Bias Correction State & Coefficients From The Storm DB's db_info (Raw Signal Is Corrected Like The Imported storm)
            db_info = dbObj.get_db_info();
            config.tc_bias_corrected = db_info.tc_bias_corrected;
            config.chs_swl_b_a = db_info.chs_swl_b_a;
            config.chs_swl_b_r = db_info.chs_swl_b_r;
            chessPlot = CHESSPlotsBase(config, [], storm, [], [], [], base_fnt, app.wProj.unitsys);
            % Plot (And Tab Table) According To Data Type
            cla(ax);
            legend(ax, 'off');
            field_names = {'SWL', 'Hm0', 'Tp'};
            stm_data = aux_methods.pull_data(ds_query, storm);
            stm_list = cell2mat(stm_data(:, 1));
            y_field = app.FieldToPlotDropDown.Value;
            y_lbl = app.FieldToPlotDropDown.Items{strcmp(field_names, y_field)};
            switch app.DataTypeDropDown.Value
                case 'Peaks' % Storm Data Space: Y Field Vs X Field
                    x_field = app.StormIDDropDown.Value;
                    chessPlot.storm_data_space_plot(ax, ds_query, y_field, x_field);
                    if tab_mode
                        x_lbl = app.StormIDDropDown.Items{strcmp(field_names, x_field)};
                        peak_of = @(col) cellfun(@(x) max(x(:, col), [], 'omitnan'), stm_data(:, 2));
                        app.StormDBTable.ColumnName = {'Storm ID', y_lbl, x_lbl};
                        app.StormDBTable.Data = [stm_list, peak_of(strcmp(field_names, y_field)), peak_of(strcmp(field_names, x_field))];
                    end
                case 'Timeseries' % Event Hydrograph: storm (Interpolated) + CHS_Data (Raw)
                    event_id = str2double(app.StormIDDropDown.Value);
                    chessPlot.event_hydrograph_plot(ax, ds_query, event_id, y_field, app.load_chs_data(dbObj));
                    if tab_mode
                        hydrograph = stm_data{stm_list == event_id, 2};
                        app.StormDBTable.ColumnName = {'Event Duration [hrs]', y_lbl};
                        app.StormDBTable.Data = [(hydrograph(:, 5) - hydrograph(1, 5))*24, hydrograph(:, strcmp(field_names, y_field))];
                    end
            end
        end

        function [st_types, has_peaks, has_timeseries] = populate_data_types(app, storm)
            % Data Type Tree From Storm DB (NodeData Holds Library Symbol)
            delete(app.DataTypesTree.Children(:));
            st_types = fieldnames(storm);
            data_types = fieldnames(storm.(st_types{1}));
            has_peaks = any(contains(data_types, 'Peaks'));
            has_timeseries = any(contains(data_types, 'Timeseries'));
            if has_peaks
                match_types = fieldnames(storm.(st_types{1}).('Peaks'));
                peaksTree = uitreenode(app.DataTypesTree, "Text", 'Peaks', 'NodeData', 'use_peaks');
                for i = 1:length(match_types)
                    switch match_types{i}
                        case 'WLP'
                            symbol = 'create_wlp';
                        case 'WHP'
                            symbol = 'create_whp';
                        otherwise
                            symbol = 'peaks_default';
                    end
                    uitreenode(peaksTree, "Text", match_types{i}, 'NodeData', symbol);
                end
            end
            if has_timeseries
                uitreenode(app.DataTypesTree, "Text", 'Timeseries', 'NodeData', 'use_timeseries');
            end
        end

        function populate_inputs_table(app)
            % Inputs Table: Pertinent Rows Only (Library Template), Values From tableState (All Workflows)
            app.store_table_values();
            [pse_name, alt_name] = app.get_active_names();
            pse_obj = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
            if isempty(pse_obj)
                app.WorkflowInputsTable.Data = cell(0, 3);
                return
            end
            tpl = app.wProj.get_library_template(pse_obj.pse_type);
            resp_checked = app.get_checked_symbols(app.ResponsesTree);
            rows = cell(0, 4);
            % Forcing: Storm Duration Always Shown (Dn50 Uses It For Timeseries Runs Too)
            rows = [rows; tpl.forcing(strcmp(tpl.forcing(:, 1), 'storm_duration'), :)];
            % Response Parameters (Template Rows Not Described As "Compute...", e.g. compute_damaging_depth_Ks)
            % Shown When The Response They Extend Is Checked
            resp_sym = tpl.responses(:, 1);
            is_switch = startsWith(tpl.responses(:, 2), 'Comput');
            for rr = find(~is_switch)'
                parent = resp_sym(is_switch & cellfun(@(x) startsWith(resp_sym{rr}, [x '_']), resp_sym));
                if any(ismember(parent, resp_checked))
                    rows = [rows; tpl.responses(rr, :)]; %#ok<AGROW>
                end
            end
            % Workflow Sections
            switch app.WorkflowDropDown.Value
                case 1 % PROS-RB
                    rows = [rows; tpl.pros];
                case 4 % PROS-FB (Library Forces Forcing HCs)
                    rows = [rows; tpl.pros(~strcmp(tpl.pros(:, 1), 'pros_compute_forcing_HC'), :)];
                case 2 % Deterministic: No Hazard Curve, CL Or LCS Inputs
                case 3 % LCS
                    rows = [rows; tpl.mcs];
                    % CSR-DPA (Library chess_csr): Rubble Mound Timeseries Runs Only; Its Settings Only While csr_call_dpa Is 1.
                    % csr_compute_S_submerged Is Never Shown. Hidden Values Stay In tableState
                    if chessProject.get_struc_type(pse_obj.pse_type) == 3 && ismember('use_timeseries', app.get_checked_symbols(app.DataTypesTree))
                        csr = tpl.csr(~strcmp(tpl.csr(:, 1), 'csr_compute_S_submerged'), :);
                        is_call = strcmp(csr(:, 1), 'csr_call_dpa');
                        dpa_on = csr{is_call, 4};
                        if isfield(app.tableState, 'csr_call_dpa')
                            dpa_on = app.tableState.csr_call_dpa;
                        end
                        if ~isequal(dpa_on, 1)
                            csr = csr(is_call, :);
                        end
                        rows = [rows; csr];
                    end
            end
            % 0/1 Switch Rows (Template Description Marks Them) Shown As Checkboxes: Logical Values In The Data Cell
            is_flag = contains(rows(:, 2), {'(0/1)', '(0 - Off / 1 - On)'});
            % Field Label = Description [Units]
            has_units = ~strcmp(rows(:, 3), 'NA') & ~cellfun(@isempty, rows(:, 3));
            rows(has_units, 2) = strcat(rows(has_units, 2), {' '}, rows(has_units, 3));
            % Values: tableState (Entered/Restored) Else Template Default
            tbl = [rows(:, 1:2), rows(:, 4)];
            for kk = 1:size(tbl, 1)
                if isfield(app.tableState, tbl{kk, 1})
                    tbl{kk, 3} = app.tableState.(tbl{kk, 1});
                end
            end
            tbl(is_flag, 3) = cellfun(@(v) isequal(v, 1), tbl(is_flag, 3), 'un', false);
            app.WorkflowInputsTable.Data = tbl;
        end

        function populate_project_panel(app)
            % Update Project Tree
            app.update_project_tree();
            % No Projects Loaded
            if isempty(app.chess_projects)
                app.ProjectDropDown.Items = {};
                app.PSEDropDown.Items = {};
                app.AlternativeDropDown.Items = {};
                app.ProjectPathEditField.Value = '';
                app.PSEMenu.Enable = false;
                app.AlternativeMenu.Enable = false;
                return
            end
            % Project Dropdown
            projList = {app.chess_projects.name};
            app.ProjectDropDown.Items = projList;
            app.ProjectDropDown.Value = projList{app.proj_indx};
            app.ProjectPathEditField.Value = app.wProj.get_project_field('path', '', '', 'proj');
            % PSE & Alternative Dropdowns
            [pse_name, alt_name] = app.get_active_names();
            pseList = app.wProj.pse_list;
            app.PSEDropDown.Items = pseList;
            if ~isempty(pse_name)
                app.PSEDropDown.Value = pse_name;
                app.AlternativeDropDown.Items = app.wProj.alternative_list{app.pse_indx};
            else
                app.AlternativeDropDown.Items = {};
            end
            if ~isempty(alt_name)
                app.AlternativeDropDown.Value = alt_name;
            end
            % Menus
            app.PSEMenu.Enable = true;
            app.AlternativeMenu.Enable = ~isempty(pseList);
        end

        function populate_pse_responses(app, has_timeseries)
            % Response Tree From Library Template "Structure Responses" (NodeData Holds Library Symbol)
            delete(app.ResponsesTree.Children(:));
            [pse_name, alt_name] = app.get_active_names();
            pse_obj = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
            if isempty(pse_obj)
                return
            end
            tpl = app.wProj.get_library_template(pse_obj.pse_type);
            resp_sym = tpl.responses(:, 1);
            for rr = 1:length(resp_sym)
                % Response Switches Are Described As "Compute..."; Parameter Rows Go To Inputs Table
                if ~startsWith(tpl.responses{rr, 2}, 'Comput')
                    continue
                end
                % Overtopping Volume Requires Timeseries
                if strcmp(resp_sym{rr}, 'compute_q_vol') && ~has_timeseries
                    continue
                end
                uitreenode(app.ResponsesTree, "Text", tpl.responses{rr, 2}, 'NodeData', resp_sym{rr});
            end
        end

        function populate_constants_table(app)
            % [NEW] Project Panel Constants Table (Display Only; Gravity Constant Is Read By Label): Water Density (Active PSE,
            % As Used By Runs), Gravity (Library Value), Offshore Elevation (shown_offshore_elevation) And The Run Tab Storm
            % DB's Bias & Uncertainty (db_info, Library storm_db_info). No Storm DB: '-'. db_info Missing Fields: Re-Import
            gravity = 9.80665; % m/s^2, Library Value (Setup/call_input_parser.m)
            density = {'-', NaN};
            offshore = {'-', '-'};
            dbObj = [];
            if app.has_active_alt()
                [pse_name, alt_name] = app.get_active_names();
                tbl = app.wProj.get_project_field('pse_tables', pse_name, alt_name, 'alt');
                geo = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                density = {erase(tbl{strcmp(tbl(:, 1), 'water_density'), 3}, {'[', ']'}), geo.water_density};
                if ~isempty(app.StormDBDropDown_run.Items)
                    dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
                end
                unitLib = app.auxLib.update_unit_system(app.wProj.unitsys);
                offshore = {unitLib.unitSymbols.length, app.shown_offshore_elevation(dbObj)};
            end
            % Storm DB Rows. DET Runs Without Forcing Uncertainty
            u_note = '';
            if app.WorkflowDropDown.Value == 2
                u_note = ' (Not Used By DET)';
            end
            db_rows = {['SWL Ua' u_note], 'm'; ['SWL Ur' u_note], '-'; ['Hm0 Ua' u_note], 'm'; ['Hm0 Ur' u_note], '-';...
                ['Tp Ur (From Hm0 Ur)' u_note], '-'; 'SWL Ba (Applied To TC At DB Build)', 'm'; 'SWL Br (Applied To TC At DB Build)', '-';...
                'TC Bias Corrected', '-'; 'B&U Source (SWL / Hm0)', '-'};
            if isempty(dbObj)
                db_rows(:, 3) = {'-'};
            elseif ~storm_db_info('valid', dbObj.db_info)
                db_rows(:, 3) = {'n/a (re-import DB)'};
            else
                info = dbObj.db_info;
                yes_no = {'No', 'Yes'};
                db_rows(:, 3) = {info.chs_swl_u_a; info.chs_swl_u_r; info.chs_hm0_u_a; info.chs_hm0_u_r;...
                    sqrt(1 + info.chs_hm0_u_r) - 1; info.chs_swl_b_a; info.chs_swl_b_r;... % Tp Ur As In apply_uncertainty
                    yes_no{1 + isequal(info.tc_bias_corrected, 1)}; [info.chs_swl_bu_source ' / ' info.chs_hm0_bu_source]};
            end
            app.ConstantsTable.Data = [{'Water Density', density{:}; 'Gravity Constant', 'm/s^2', gravity;...
                'Offshore Elevation (Storm DB / PSE)', offshore{:}}; db_rows];
        end

        function populate_pse_table(app)
            % [NEW] PSE Viewer Table: The Active Alternative's pse_tables. The offshore_elevation Row (Fallback Value) Is Shown Only
            % When The Run Tab Storm DB Has No Study Grid Value, Or There Is No Run Tab Storm DB. Display Only: pse_tables Keeps It
            if ~app.has_active_alt()
                app.PSETable.Data = cell(0, 4);
                return
            end
            [pse_name, alt_name] = app.get_active_names();
            tbl = app.wProj.get_project_field('pse_tables', pse_name, alt_name, 'alt');
            dbObj = [];
            if ~isempty(app.StormDBDropDown_run.Items)
                dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
            end
            if ~isempty(dbObj) && ~isempty(app.db_offshore_elevation(dbObj))
                tbl = tbl(~strcmp(tbl(:, 1), 'offshore_elevation'), :);
            end
            app.PSETable.Data = tbl;
        end

        function populate_stormDB_panel(app, dbObj, db_list)
            % Load Storm (Cached)
            storm = app.load_storm(dbObj);
            % Storm DB
            app.StormDBDropDown.Items = db_list;
            app.StormDBDropDown.Value = dbObj.db_name;
            % Cascade: Storm Type -> Data Type -> Match Type (Keeps Valid Selections)
            app.StormTypeDropDown.Items = fieldnames(storm);
            data_type = fieldnames(storm.(app.StormTypeDropDown.Value));
            app.DataTypeDropDown.Items = data_type(contains(data_type, {'Peaks','Timeseries'}));
            app.MatchTypeDropDown.Items = fieldnames(storm.(app.StormTypeDropDown.Value).(app.DataTypeDropDown.Value));
            % Field To Plot (ItemsData = Library Field Names)
            fields_to_plot = {'Water Level','Wave Height (Hm0)', 'Peak Period'};
            field_names = {'SWL', 'Hm0', 'Tp'};
            app.FieldToPlotDropDown.Items = fields_to_plot;
            app.FieldToPlotDropDown.ItemsData = field_names;
            % Second Dropdown: X Field For Peaks (Data Space Plot), Storm ID For Timeseries (Hydrograph)
            switch app.DataTypeDropDown.Value
                case 'Peaks'
                    app.StormIDDropDownLabel.Text = 'X Field:';
                    app.StormIDDropDown.Items = fields_to_plot;
                    app.StormIDDropDown.ItemsData = field_names;
                    if strcmp(app.StormIDDropDown.Value, app.FieldToPlotDropDown.Value)
                        app.StormIDDropDown.Value = field_names{2}; % Default: SWL Vs Hm0
                    end
                case 'Timeseries'
                    app.StormIDDropDownLabel.Text = 'Storm ID:';
                    app.StormIDDropDown.ItemsData = {};
                    app.StormIDDropDown.Items = cellfun(@num2str, storm.(app.StormTypeDropDown.Value).(app.DataTypeDropDown.Value).(app.MatchTypeDropDown.Value)(:,1), 'UniformOutput', false);
            end
            app.StormIDDropDown.Enable = true;
            app.updateStormDBPlot([]);
        end

        function populateResultsTab(app)
            % Load Results For Active Alternative / Workflow / Storm DB (Auto-Load) + Status Line
            app.resultsState = [];
            if ~app.has_active_alt() || isempty(app.StormDBDropDown_run.Value)
                app.reset_tab('results');
                app.set_sim_status('');
                return
            end
            % Switch Pending (Key Differs From The Last Resolved One): sync_saved_sim Decides Whether To Load
            if ~isequal(app.sim_key(), app.simKey)
                app.reset_tab('results');
                app.set_sim_status('');
                return
            end
            % Load Declined ("Keep current inputs") For This Key: Results Stay Cleared Until The Key Changes, The Run Dialog's
            % "Load existing" Loads It Or A Run Succeeds (Internal Refreshes Do Not Load It)
            if ~isempty(app.declinedSimKey) && isequal(app.sim_key(), app.declinedSimKey)
                app.reset_tab('results');
                app.set_sim_status('Current simulation already exists. Load declined.');
                return
            end
            [config_file, ~, ~, ~, out_files] = app.get_config_path();
            [has_sim, label] = app.saved_sim();
            if ~has_sim
                app.reset_tab('results');
                app.set_sim_status(['No saved simulation for ' label '.']);
                return
            end
            % Load Results & Run Config (Other Plot Data Is Loaded On Demand By plot_project_responses)
            S = load(out_files.resp_data, 'Resp');
            % Run's Structure (Saved By The Library call_project_response, With The Run's Resolved Offshore Elevation), Not The Current PSE
            C = load(config_file, 'config', 'structure');
            C.config = app.localize_saved_config(C.config);
            state = struct('Resp', S.Resp, 'config', C.config, 'structure', C.structure,...
                'project_forcing', [], 'prob_mass', [], 'out_files', out_files,...
                'dbObj', app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db'));
            if C.config.workflow == 3 && isfile(out_files.project_forcing) % LCS Plots Need Project Forcing
                P = load(out_files.project_forcing, 'project_forcing');
                state.project_forcing = P.project_forcing;
            end
            app.resultsState = state;
            % Status: Saved Run Config Vs Current Run Tab Inputs
            app.set_sim_status(app.results_status());
            % Plot Types Available For This Run (Library Plot Catalog)
            cat = app.results_plot_catalog();
            app.PlotTypeDropDown.Items = {cat.label};
            app.PlotTypeDropDown.ItemsData = {cat.key};
            app.Panel_2.Enable = true;
            app.update_results_controls('plot');
            app.plot_project_responses();
        end

        function populateSimulationTab(app)
            % Snapshot Current Run Tab State (Restored If Context Is Unchanged)
            app.get_sim_state();
            % Nothing To Show Without An Alternative And A Storm DB
            dbList = [];
            if app.has_active_alt()
                dbList = app.wProj.get_project_field('stormDB_list', '', '', 'proj');
            end
            if isempty(dbList)
                app.reset_tab('sim');
                app.simContext = '';
                return
            end
            [pse_name, alt_name] = app.get_active_names();
            context = strjoin({app.wProj.name, pse_name, alt_name}, '|');
            new_context = ~strcmp(context, app.simContext);

            % 1. Storm DB (Assigned DB On New Context, Else Keep User Choice)
            db_names = {dbList.db_name};
            current_db = app.StormDBDropDown_run.Value;
            app.StormDBDropDown_run.Items = db_names;
            db_indx = app.wProj.get_project_field('stormDB_assignment', pse_name, alt_name, 'alt');
            if new_context && ~isempty(db_indx) && db_indx <= numel(db_names)
                app.StormDBDropDown_run.Value = db_names{db_indx};
            elseif ~new_context && any(strcmp(current_db, db_names))
                app.StormDBDropDown_run.Value = current_db;
            end
            dbObj = dbList(strcmp(db_names, app.StormDBDropDown_run.Value));

            % 2. Storm Types & Data Types From Storm DB
            storm = app.load_storm(dbObj);
            st_types = fieldnames(storm);
            if all(ismember({'XC','TC'}, st_types))
                st_types = [{'CC'}; st_types];
            end
            app.StormTypeDropDown_2.Items = st_types;
            [~, ~, has_timeseries] = app.populate_data_types(storm);

            % 3. Tides (File Names Shown, Full Paths Passed To The Library As config.tide_file)
            tide_files = app.wProj.tide_files_list(:);
            [~, tide_names, tide_ext] = cellfun(@fileparts, tide_files, 'un', false);
            app.TidesFileDropDown.Items = [{'None'}; strcat(tide_names, tide_ext)];
            app.TidesFileDropDown.ItemsData = [{'None'}; tide_files];

            % 4. Responses (Library Template For PSE Type)
            app.populate_pse_responses(has_timeseries);

            % 5. Restore State: New Context Keeps The Current Inputs (Mapped To This PSE's Template), Same Context The Snapshot.
            % A Saved Simulation Of A New Alternative / Workflow / Storm DB Is Offered Afterwards (sync_saved_sim)
            if new_context
                app.set_sim_state(3, '');
            else
                app.set_sim_state(1, '');
            end
            app.simContext = context;

            % 6. Inputs Table & Run Button (Constants Table: Callers Run populate_constants_table Next)
            app.set_input_table();
        end

        function project_field_ini(app)
            % This function ensures all chess_project fields dimensions stay
            % in sync. Needs to be called once a PSE/Alternative is created
            if ~isempty(app.wProj)
                app.wProj.sync_alt_fields();
            end
        end % End Function

        function [offshore_elevation, elevation_source] = pse_popout_offshore_elevation(app)
            % [NEW] Offshore Elevation For The PSE Pop-Out Text Box: Study Grid Value Of The Storm DB Selected On The Run Tab
            % (Defaults To The Alternative's Last-Run DB), Else The PSE Geometry Value. None -> [] (chessProject.plot_geometry
            % Keeps The PSE Value, Else The Library Shows 'n/a').
            offshore_elevation = [];
            elevation_source = '';
            if isempty(app.wProj) || isempty(app.wProj.stormDB_list) || isempty(app.StormDBDropDown_run.Items)
                return
            end
            db_name = app.StormDBDropDown_run.Value;
            dbObj = app.wProj.get_project_field('stormDB_list', db_name, '', 'db');
            if isempty(dbObj)
                return
            end
            [offshore_elevation, elevation_source] = app.shown_offshore_elevation(dbObj);
            if strcmp(elevation_source, 'Storm DB grid')
                elevation_source = ['Storm DB: ' db_name];
            elseif isnan(offshore_elevation)
                offshore_elevation = [];
            end
        end

        function value = pull_input_table_value(app, table_data, field_to_get)
            pull_indx = strcmp(table_data(:, 1), field_to_get);
            if ~any(pull_indx)
                value = [];
            else
                value = +table_data{find(pull_indx, 1), end};
            end
        end

        function refresh_active_view(app, user_switch)
            % [NEW] Single Refresh Pipeline: Project Panel & Tree -> PSE Tab -> Storm DB Tab -> Run Tab -> Results Tab -> Saved Simulation.
            %   user_switch = true: Tree / Project / PSE / Alternative Selection By The User (May Ask To Load A Saved Simulation)
            if nargin < 2
                user_switch = false;
            end
            app.populate_project_panel();
            % PSE Viewer
            if app.has_active_alt()
                [pse_name, alt_name] = app.get_active_names();
                app.wProj.plot_geometry(pse_name, alt_name, app.PSEAxes, app.plotFontSize);
            else
                app.reset_tab('pse');
            end
            % Storm DB Viewer (Assigned DB For Alternative, Else First)
            dbList = [];
            if ~isempty(app.wProj)
                dbList = app.wProj.get_project_field('stormDB_list', '', '', 'proj');
            end
            if isempty(dbList)
                app.reset_tab('db');
            else
                db_indx = [];
                if app.has_active_alt()
                    [pse_name, alt_name] = app.get_active_names();
                    db_indx = app.wProj.get_project_field('stormDB_assignment', pse_name, alt_name, 'alt');
                end
                if isempty(db_indx) || db_indx > numel(dbList)
                    db_indx = 1;
                end
                app.populate_stormDB_panel(dbList(db_indx), {dbList.db_name});
            end
            % CHESS Run & Results Viewer
            app.populateSimulationTab();
            app.populate_pse_table();
            app.populate_constants_table();
            app.populateResultsTab();
            % Saved Simulation Of A New Alternative / Workflow / Storm DB (Dialog Only On A User Switch)
            app.sync_saved_sim(user_switch);
        end

        function rename_tree_node(app, node)
            % Project Tree Right-Click "Rename...": Project, PSE Or Alternative Of node (chessProject.rename: Folder, Stored Paths,
            % Saved Run Configs). Session State Holding The Old Paths/Names Follows The Rename, So The View Stays As It Was
            if app.runActive
                uialert(app.UIFigure, 'Rename is not available while a run is in progress.', 'Rename', 'Icon', 'info');
                return
            end
            level = node.NodeData.type;
            [pse_name, alt_name] = deal('');
            switch level
                case 'proj'
                    proj_node = node;
                case 'pse'
                    proj_node = node.Parent;
                    pse_name = node.Text;
                case 'alt'
                    proj_node = node.Parent.Parent;
                    pse_name = node.Parent.Text;
                    alt_name = node.Text;
            end
            proj_names = {app.chess_projects.name};
            proj = app.chess_projects(strcmp(proj_names, proj_node.Text));
            current = node.Text;
            % parent_dir: Folder Holding The Item (A Folder Or File Of The New Name There Is Shown While Typing)
            switch level
                case 'proj'
                    siblings = proj_names(~strcmp(proj_names, current));
                    title_str = 'Rename Project';
                    prompt = sprintf('New name for project "%s":', current);
                    parent_dir = proj.path;
                case 'pse'
                    siblings = proj.pse_list(~strcmp(proj.pse_list, current));
                    title_str = 'Rename PSE';
                    prompt = sprintf('New name for PSE "%s":', current);
                    parent_dir = fullfile(proj.path, proj.name, 'PSE');
                case 'alt'
                    alts = proj.alternative_list{strcmp(proj.pse_list, pse_name)};
                    siblings = alts(~strcmp(alts, current));
                    title_str = 'Rename Alternative';
                    prompt = sprintf('New name for alternative "%s" (PSE %s):', current, pse_name);
                    parent_dir = fullfile(proj.path, proj.name, 'PSE', pse_name);
            end
            app.auxLib.editfield_input_dlgbox(prompt, title_str, current, @(v) chessProject.name_problem(v, siblings, current, parent_dir), 'Rename');
            new_name = app.auxLib.dialog_answer;
            if isempty(new_name)
                return
            end
            % Active Selection And Run Tab Context Before The Rename (Kept, Under The New Name)
            act_proj = app.wProj.name;
            [act_pse, act_alt] = app.get_active_names();
            ctx_before = strjoin({act_proj, act_pse, act_alt}, '|');
            try
                info = proj.rename(level, new_name, pse_name, alt_name, siblings);
            catch ME
                app.auxLib.error_dlgbox(['Could not rename "' current '": ' ME.message]);
                return
            end
            is_active = proj == app.wProj;
            switch level
                case 'proj'
                    if is_active
                        act_proj = new_name;
                    end
                case 'pse'
                    if is_active && strcmp(act_pse, current)
                        act_pse = new_name;
                    end
                case 'alt'
                    if is_active && strcmp(act_pse, pse_name) && strcmp(act_alt, current)
                        act_alt = new_name;
                    end
            end
            if strcmp(app.simContext, ctx_before)
                app.simContext = strjoin({act_proj, act_pse, act_alt}, '|');
            end
            % Paths Under The Moved Folder: Cached storm/CHS_Data Dropped (Reloaded From The New Paths); Saved Simulation Keys
            % (get_config_path) And Older-DB Alerts Remapped; Loaded Results Reloaded By refresh_active_view
            remap = @(p) chessProject.relocate_path(p, info.old_dir, info.new_dir);
            keys = app.stormCache.keys();
            moved = keys(~strcmp(keys, cellfun(remap, keys, 'un', false)));
            if ~isempty(moved)
                remove(app.stormCache, moved);
            end
            app.simKey = remap(app.simKey);
            app.declinedSimKey = remap(app.declinedSimKey);
            app.oldDbWarned = cellfun(remap, app.oldDbWarned, 'un', false);
            app.resultsState = [];
            app.save_project(proj);
            app.set_active_selection(act_proj, act_pse, act_alt);
            app.refresh_active_view();
            if ~isempty(info.failed)
                uialert(app.UIFigure, sprintf('Renamed to "%s", but these files could not be updated:\n%s', new_name,...
                    strjoin(info.failed, newline)), 'Rename', 'Icon', 'warning');
            end
        end

        function reset_empty_axes(app, ax)
            % [NEW] Empty Viewer Axes (Nothing Plotted): Full Reset, Then The Design View Look, So An Axes Cleared After A
            % Plot Looks The Same As At Startup. Plots Are Free To Restyle It
            cla(ax, 'reset');
            set(ax, 'Box', 'on', 'XGrid', 'on', 'YGrid', 'on', 'ZGrid', 'on', 'XMinorGrid', 'on', 'YMinorGrid', 'on',...
                'XMinorTick', 'on', 'YMinorTick', 'on');
            xlabel(ax, 'X Distance');
            ylabel(ax, 'Elevation');
        end

        function reset_results_axes(app)
            % [NEW] Clean Results Axes Between Plot Types (Plots, Colorbar, Legend, Rulers, Scales) & Keep Its Right-Click Menu
            ax = app.PSEAxes_3;
            app.hide_results_mirror();
            colorbar(ax, 'off');
            legend(ax, 'off');
            cla(ax, 'reset');
            ax.ContextMenu = app.resultsMenu;
        end

        function reset_tab(app, tab_to_reset)
            switch tab_to_reset
                case 'pse'
                    %%% PSE Tab
                    app.reset_empty_axes(app.PSEAxes);
                    app.PSETable.Data = cell(0, 4);
                case 'db'
                    %%% Storm Database Tab
                    app.reset_empty_axes(app.StormDBAxes);
                    app.StormDBDropDown.Items = {}; % Reset Items For All Dropdowns
                    app.StormIDDropDown.Items = {};
                    app.FieldToPlotDropDown.Items = {};
                    app.MatchTypeDropDown.Items = {};
                    app.DataTypeDropDown.Items = {};
                    app.StormTypeDropDown.Items = {};
                    app.StormDBTable.Data = cell(0, 2);
                case 'sim'
                    %%% CHESS Run Tab
                    app.ApplyDepthLimitationCheckBox.Value = false; % Reset Depth Limitation
                    app.SeaLevelChangeEditField.Value = 0; % Reset Sea Level Change
                    app.StormDBDropDown_run.Items = {}; % Reset Items On Dropdowns
                    app.StormTypeDropDown_2.Items = {};
                    app.TidesFileDropDown.ItemsData = {};
                    app.TidesFileDropDown.Items = {'None'};
                    delete(app.ResponsesTree.Children); % Reset Checkbox Trees
                    delete(app.DataTypesTree.Children);
                    app.WorkflowInputsTable.Data = cell(0, 3);
                    app.RunSimulationButton.Enable = false; % Disable Run Button
                case 'results'
                    %%% CHESS Results Viewer Tab
                    app.reset_results_axes(); % Clear Axis (Plots, Colorbar, Legend, Rulers)
                    app.reset_empty_axes(app.PSEAxes_3); % Empty State:
                    app.PSETable_3.Data = cell(0, 3); % Clear Data Table
                    app.PlotTypeDropDown.ItemsData = {};
                    app.PlotTypeDropDown.Items = {};
                    app.StormIDDropDown_results.Items = {}; % Reset Storm ID
                    app.FieldToPlotDropDown_results.Items = {};
                    app.MatchTypeDropDown_results.Items = {};
                    app.DataTypeDropDown_results.Items = {};
                    app.StormTypeDropDown_results.Items = {};
                    app.Panel_2.Enable = false; % Make Sure To Disable All Items For New Project
            end
        end

        function restore_leftover_backups(app, bk)
            % [NEW] *_prev Files At Run Start: The Last Run Never Finished (e.g. MATLAB Closed), Put The Previous Run Back First
            for kk = 1:numel(bk)
                if isfile(bk(kk).prev)
                    movefile(bk(kk).prev, bk(kk).file, 'f');
                end
            end
        end

        function failed = restore_sim_files(app, backed)
            % [NEW] Failed Run: Put Back What This Run Backed Up (*_prev Moved Over Whatever The Run Left) And Delete The Files
            % It Created Where There Were None. Best Effort: failed Lists What Could Not Be Restored
            failed = {};
            for kk = 1:numel(backed)
                try
                    if backed(kk).existed
                        if isfile(backed(kk).prev)
                            movefile(backed(kk).prev, backed(kk).file, 'f');
                        end
                    elseif isfile(backed(kk).file)
                        delete(backed(kk).file);
                    end
                catch ME
                    failed{end+1} = sprintf('%s (%s)', backed(kk).file, ME.message); %#ok<AGROW>
                end
            end
        end

        function [lbl, items, on] = results_aux_items(app, key, data, ds_query)
            % [NEW] Storm ID Dropdown Per Plot Type: Event ID, Life Cycle Or A Plot Option
            lbl = 'Storm ID:';
            items = {'-'};
            on = false;
            lc_items = @(n) [{'All'}, arrayfun(@num2str, 1:n, 'un', false)];
            switch key
                case 'hc' % CC Is Derived: No Run Sample Or Storm Set To Overlay
                    lbl = 'Add Events:';
                    if strcmp(ds_query.storm_type, 'CC')
                        items = {'Off'};
                    else
                        items = {'Off', 'Run Sample', 'Storm Values'};
                        on = true;
                    end
                case 'det_scatter' % X Axis (Color: Right-Click Plot Options)
                    lbl = 'X Axis:';
                    items = app.det_field_list(data);
                    on = true;
                case 'det_table' % Timeseries: Storm Whose Hydrographs Are Shown | Peaks: All Storms
                    if strcmp(ds_query.data_type, 'Timeseries')
                        items = arrayfun(@num2str, data.storm_id, 'un', false);
                        on = true;
                    else
                        items = {'All'};
                    end
                case 'event' % Timeseries: One Event | Peaks: All Events
                    if strcmp(ds_query.data_type, 'Timeseries')
                        stm_data = aux_methods.pull_data(ds_query, app.load_storm(app.resultsState.dbObj));
                        items = arrayfun(@num2str, cell2mat(stm_data(:, 1)), 'un', false);
                        on = true;
                    else
                        items = {'All'};
                    end
                case 'annual'
                    lbl = 'Normalize:';
                    items = {'percent', 'count'};
                    on = true;
                case 'armor' % Life Cycle Selection Applies To S_* Damage Series Only
                    lbl = 'Life Cycle:';
                    field = app.FieldToPlotDropDown_results.Value;
                    items = {'All'};
                    if startsWith(field, 'S_') && isfield(data, 'S') && isfield(data.S, field)
                        items = lc_items(numel(data.S.(field)));
                        on = true;
                    end
                case 'sampling'
                    lbl = 'Life Cycle:';
                    items = lc_items(numel(aux_methods.pull_data(ds_query, app.resultsState.project_forcing)));
                    on = true;
                case 'submergence'
                    lbl = 'X Scale:';
                    items = {'Linear', 'Log'};
                    on = true;
            end
        end

        function [edges, labels] = results_bin_edges(app, chessPlot, ds_query, field, edges)
            % [NEW] Annual Distribution Bin Edges (Plot Option Or Library Estimate) & Results Table Column Labels.
            % No Finite Response Values: No Edges, No Labels (The Library Plot Shows "No finite response values to plot")
            if isempty(edges)
                data = aux_methods.pull_data(ds_query, app.resultsState.Resp);
                if isfield(data, field)
                    resp_vector = data.(field);
                else
                    resp_vector = data.S.(field);
                end
                if ~any(cellfun(@(x) any(isfinite(x(:))), resp_vector))
                    labels = {};
                    return
                end
                [~, ~, ~, ~, ~, ~, x_log] = response_library_headers(app.resultsState.config, field);
                edges = chessPlot.generate_bin_guess(resp_vector, 5, x_log);
            end
            edges = edges(:)';
            % Library Binning Uses [-Inf, edges, edges(end)+1, Inf]
            final_edges = [-Inf, edges, edges(end)+1, Inf];
            labels = arrayfun(@(a, b) sprintf('%g to %g', a, b), final_edges(1:end-1), final_edges(2:end), 'un', false);
            labels{1} = sprintf('< %g', edges(1));
            labels{end} = sprintf('>= %g', final_edges(end-1));
        end

        function results_control_changed(app, src)
            % [NEW] Results Dropdown Changed (Assigned In startupFcn): Refresh Dependent Dropdowns, Then Redraw.
            % Multi-Panel Plots Open (Or Refresh) The Results Pop-Out Automatically.
            levels = {app.PlotTypeDropDown, 'plot'; app.DataTypeDropDown_results, 'data'; app.MatchTypeDropDown_results, 'match';...
                app.StormTypeDropDown_results, 'storm'; app.FieldToPlotDropDown_results, 'field'};
            for ll = 1:size(levels, 1)
                if isequal(src, levels{ll, 1})
                    app.update_results_controls(levels{ll, 2});
                end
            end
            app.plot_project_responses();
            row = app.results_plot_row();
            if ~isempty(row) && row.multi_panel
                app.open_results_popout();
            end
        end

        function dts = results_data_types(app, row)
            % [NEW] Data Types In The Loaded Results That A Plot Type Supports
            Resp = app.resultsState.Resp;
            dts = intersect(row.data_types, fieldnames(Resp), 'stable');
            if row.needs_s % Armor Damage Plots: Results Must Include Damage (S)
                has_s = cellfun(@(dt) any(structfun(@(mt) any(structfun(@(st) isstruct(st) && isfield(st, 'S'), mt)), Resp.(dt))), dts);
                dts = dts(has_s);
            end
        end

        function [lbl, items] = results_field_items(app, key, data)
            % [NEW] Field To Plot Items Per Plot Type (Library Method Field Lists)
            lbl = 'Field To Plot:';
            s_fields = {};
            if isstruct(data) && isfield(data, 'S')
                s_fields = fieldnames(data(1).S)';
            end
            s_series = intersect({'S_seaside_no_repairs', 'S_seaside_with_repairs', 'S_leeside_no_repairs', 'S_leeside_with_repairs'}, s_fields, 'stable');
            switch key
                case 'hc'
                    items = {data.var};
                case {'det_scatter', 'det_compare'} % Deterministic: Forcing & Responses (Y Axis / Compared Field)
                    if strcmp(key, 'det_scatter')
                        lbl = 'Y Axis:';
                    end
                    items = app.det_field_list(data);
                case 'det_table' % Columns Are Picked In The Table Pop-Out
                    items = {};
                case 'event' % Responses The Event Viewer Recomputes For This Structure Type (And The Run Computed)
                    if isfield(data, 'var')
                        run_fields = {data.var};
                    else
                        run_fields = fieldnames(data)';
                    end
                    items = intersect(app.results_response_list('event'), run_fields, 'stable');
                case 'annual'
                    items = intersect(app.results_response_list('annual'), [fieldnames(data)', s_fields], 'stable');
                case 'armor'
                    items = [intersect({'Smax', 'LSmax'}, s_fields, 'stable'), s_series];
                case {'damaging', 'dashboard'}
                    items = s_series;
                case 'final'
                    items = intersect({'S_seaside_no_repairs', 'S_leeside_no_repairs'}, s_fields, 'stable');
                case 'submergence' % Field To Plot Relabeled As An Option
                    lbl = 'Normalize:';
                    items = {'percent', 'count'};
                otherwise % Event Sampling: No Field
                    items = {};
            end
        end

        function [project_forcing, dsw] = results_forcing_and_dsw(app)
            % [NEW] Project Forcing & Storm DB Probability Masses For The Loaded Results (Loaded Once, Kept In resultsState)
            if isempty(app.resultsState.project_forcing) && isfile(app.resultsState.out_files.project_forcing)
                P = load(app.resultsState.out_files.project_forcing, 'project_forcing');
                app.resultsState.project_forcing = P.project_forcing;
            end
            if isempty(app.resultsState.prob_mass)
                S = load(app.resultsState.dbObj.output_paths.storm_db, 'prob_mass');
                app.resultsState.prob_mass = S.prob_mass;
            end
            project_forcing = app.resultsState.project_forcing;
            dsw = app.resultsState.prob_mass;
        end

        function fits = results_mirror_fits(app, row)
            % [NEW] Does The Results Plot Cell (StormDBViewerGrid_2 Row 1) Reach The Plot's Minimum Mirror Size?
            % The Cell Is Derived From The Results Tab Size: After A Resize The Grid's Own Positions Are Still The Old Ones
            % In The Tab's SizeChangedFcn, The Tab Size Is Already The New One
            gl = app.StormDBViewerGrid_2;
            tab = app.ResultsViewerTab.Position;
            rows = gl.RowHeight;
            is_fixed = cellfun(@isnumeric, rows);
            weights = cellfun(@(r) str2double(erase(r, 'x')), rows(~is_fixed));
            free_h = tab(4) - gl.Padding(2) - gl.Padding(4) - gl.RowSpacing*(numel(rows) - 1) - sum([rows{is_fixed}]);
            cell_size = [tab(3) - gl.Padding(1) - gl.Padding(3), free_h*weights(1)/sum(weights)]; % Row 1 Is Weighted
            fits = all(cell_size >= app.results_mirror_min_size(row.key));
        end

        function sz = results_mirror_min_size(app, key)
            % [NEW] Minimum Results Plot Cell [Width Height] (px) To Also Draw A Multi-Panel Plot In The Tab (Inf: Pop-Out Only)
            switch key
                case 'event'
                    sz = [560 420];
                case {'dashboard', 'scheme'}
                    sz = [1000 600];
                otherwise % det_table: Its Own Table Window
                    sz = [Inf Inf];
            end
        end

        function cat = results_plot_catalog(app)
            % [NEW] Results Plot Types -> CHESS-Library Plot Methods (One Row Per Plot), Filtered For The Loaded Run.
            %   key | label | workflows | data types | needs S (armor damage) | rubble mound only | multi-panel (pop-out only) | plot options
            rows = {
                'hc',          'Hazard Curve',                    [1 4],   {'Peaks','Timeseries'}, false, false, false, {}
                'det_scatter', 'Response vs Forcing',             2,       {'Peaks','Timeseries'}, false, false, false, {'c_field'}
                'det_table',   'Response Table',                  2,       {'Peaks','Timeseries'}, false, false, true,  {}
                'det_compare', 'Peaks vs Timeseries',             2,       {'Peaks'},              false, false, false, {}
                'event',       'Storm Event Viewer',              [1 2 3 4], {'Peaks','Timeseries'}, false, false, true,  {}
                'annual',      'Annual Response Distribution',    3,       {'Peaks','Timeseries'}, false, false, false, {'bin_edges','cb_lim','grid_on','cb_log'}
                'armor',       'Slope Armor Damage',              3,       {'Timeseries'},         true,  true,  false, {}
                'sampling',    'Event Sampling Distribution',     3,       {'Peaks','Timeseries'}, false, false, false, {}
                'damaging',    'Damaging Instances Distribution', 3,       {'Peaks','Timeseries'}, true,  false, false, {}
                'submergence', 'PSE Submergence Distribution',    3,       {'Timeseries'},         false, false, false, {'cb_lim','cb_log'}
                'final',       'Final Damage State',              3,       {'Timeseries'},         true,  false, false, {}
                'dashboard',   'Mound Damage Dashboard',          3,       {'Timeseries'},         true,  true,  true,  {'bin_edges','cb_lim'}
                'scheme',      'Sampling Scheme Dashboard',       3,       {'Peaks','Timeseries'}, false, false, true,  {}};
            cat = cell2struct(rows, {'key','label','workflows','data_types','needs_s','rubble_mound','multi_panel','options'}, 2);
            state = app.resultsState;
            if isempty(state)
                return
            end
            keep = false(size(cat));
            for cc = 1:numel(cat)
                cat(cc).data_types = app.results_data_types(cat(cc));
                keep(cc) = ismember(state.config.workflow, cat(cc).workflows) && ~isempty(cat(cc).data_types) &&...
                    (~cat(cc).rubble_mound || state.structure.struc_type == 3) &&...
                    (~strcmp(cat(cc).key, 'det_compare') || isfield(state.Resp, 'Timeseries')); % Needs Peaks & Timeseries
            end
            cat = cat(keep);
        end

        function results_plot_options_dialog(app)
            % [NEW] Right-Click "Plot Options...": Library Plot Arguments Not Covered By The Dropdowns (Session Only)
            row = app.results_plot_row();
            if isempty(row)
                return
            end
            if isempty(row.options)
                uialert(app.UIFigure, ['"' row.label '" has no plot options.'], 'Plot Options', 'Icon', 'info');
                return
            end
            opts = app.get_results_options(row.key);
            n_opt = numel(row.options);
            fig = uifigure('Name', ['Plot Options: ' row.label], 'WindowStyle', 'modal', 'Visible', 'off', 'Position', [100 100 460 70 + 32*n_opt]);
            app.auxLib.center_on_parent(fig);
            gl = uigridlayout(fig, [n_opt + 1, 2]);
            gl.ColumnWidth = {'1x', 190};
            gl.RowHeight = [repmat({22}, 1, n_opt), {'fit'}];
            ctrl = struct();
            for oo = 1:n_opt
                switch row.options{oo}
                    case 'bin_edges'
                        lbl = 'Bin Edges (blank = auto):';
                        c = uieditfield(gl, 'text', 'Value', num2str(opts.bin_edges), 'Placeholder', 'e.g. 0 0.5 1 2  or  0:0.5:5');
                    case 'cb_lim'
                        lbl = 'Colorbar Limits (blank = auto):';
                        c = uieditfield(gl, 'text', 'Value', num2str(opts.cb_lim), 'Placeholder', 'min max');
                    case 'grid_on'
                        lbl = 'Grid:';
                        c = uicheckbox(gl, 'Text', '', 'Value', opts.grid_on);
                    case 'cb_log'
                        lbl = 'Log Colorbar:';
                        c = uicheckbox(gl, 'Text', '', 'Value', opts.cb_log);
                    case 'c_field' % Deterministic Scatter Color Variable
                        lbl = 'Color By:';
                        ds_query = struct('storm_type', app.StormTypeDropDown_results.Value,...
                            'data_type', app.DataTypeDropDown_results.Value, 'match_type', app.MatchTypeDropDown_results.Value);
                        c_items = app.det_field_list(aux_methods.pull_data(ds_query, app.resultsState.Resp));
                        c = uidropdown(gl, 'Items', c_items);
                        if any(strcmp(c_items, opts.c_field))
                            c.Value = opts.c_field;
                        elseif any(strcmp(c_items, 'Hm0_DL'))
                            c.Value = 'Hm0_DL';
                        end
                end
                c.Layout.Row = oo;
                c.Layout.Column = 2;
                l = uilabel(gl, 'Text', lbl);
                l.Layout.Row = oo;
                l.Layout.Column = 1;
                ctrl.(row.options{oo}) = c;
            end
            btn_gl = uigridlayout(gl, [1 3], 'Padding', [0 0 0 0]);
            btn_gl.Layout.Row = n_opt + 1;
            btn_gl.Layout.Column = [1 2];
            btn_gl.RowHeight = {'fit'};
            uibutton(btn_gl, 'Text', 'Reset To Defaults', 'ButtonPushedFcn', @(~, ~) finish('reset'));
            uibutton(btn_gl, 'Text', 'Cancel', 'ButtonPushedFcn', @(~, ~) finish('cancel'));
            ok_btn = uibutton(btn_gl, 'Text', 'OK', 'ButtonPushedFcn', @(~, ~) finish('ok'));
            fig.CloseRequestFcn = @(~, ~) finish('cancel');
            app.auxLib.add_dialog_keys(fig, @() finish('ok'), @() finish('cancel'), ok_btn);
            redraw = false;
            fig.Visible = 'on';
            uiwait(fig);
            if isvalid(fig)
                delete(fig);
            end
            % Redraw With The New Options (Tab, Or Pop-Out For Multi-Panel Plots)
            if redraw
                app.plot_project_responses();
                if row.multi_panel
                    app.open_results_popout();
                end
            end

            function finish(action)
                switch action
                    case 'ok'
                        new_opts = opts;
                        names = fieldnames(ctrl);
                        for kk = 1:numel(names)
                            val = ctrl.(names{kk}).Value;
                            switch names{kk}
                                case {'bin_edges', 'cb_lim'}
                                    val = app.parse_numeric_list(val);
                                    bad = any(isnan(val)) ||...
                                        (strcmp(names{kk}, 'cb_lim') && ~isempty(val) && (numel(val) ~= 2 || val(1) >= val(2))) ||...
                                        (strcmp(names{kk}, 'bin_edges') && any(diff(val) <= 0));
                                    if bad
                                        uialert(fig, 'Bin edges must be increasing numbers; colorbar limits must be "min max" with min < max.', 'Invalid Input');
                                        return
                                    end
                            end
                            new_opts.(names{kk}) = val;
                        end
                        app.resultsPlotOptions.(row.key) = new_opts;
                        redraw = true;
                    case 'reset'
                        if isfield(app.resultsPlotOptions, row.key)
                            app.resultsPlotOptions = rmfield(app.resultsPlotOptions, row.key);
                        end
                        redraw = true;
                end
                uiresume(fig);
            end
        end

        function row = results_plot_row(app)
            % [NEW] Catalog Row Of The Selected Plot Type ([] If None)
            row = [];
            if isempty(app.resultsState) || isempty(app.PlotTypeDropDown.Items)
                return
            end
            cat = app.results_plot_catalog();
            row = cat(strcmp({cat.key}, app.PlotTypeDropDown.Value));
        end

        function fields = results_response_list(app, list_name)
            % [NEW] Response Names Accepted By Library Plot Methods (Mirror Of Their Argument Validation)
            %   'annual': lcs_methods.annual_response_distribution | 'event': CHESSPlotsBase.storm_event_viewer (Per Structure Type)
            switch list_name
                case 'annual'
                    fields = {'Hm0t', 'R2p', 'R2p_SWL', 'Dn50', 'Dn50_LCBW', 'Dn50_Lee', 'p1', 'q', 'q_overflow', 'q_wave_ot',...
                        'Q_vol', 'Q_vol_overflow', 'Q_vol_wave_ot', 'DamDepthElev', 'DamDepth', 'p2dyn', 'p2sta', 'p2total',...
                        'p3dyn', 'p3sta', 'p3total', 'pu', 'X_low', 'X_up', 'X_c_surge', 'Bx', 'theta_low', 'theta_up',...
                        'theta_center', 'Bjet', 'Vjet', 'Fjet', 'S_seaside_with_repairs', 'S_seaside_no_repairs',...
                        'S_leeside_no_repairs', 'S_leeside_with_repairs'};
                case 'event'
                    overtopping = {'q', 'q_overflow', 'q_wave_ot', 'Q_vol', 'Q_vol_overflow', 'Q_vol_wave_ot', 'DamDepthElev', 'DamDepth'};
                    switch app.resultsState.structure.struc_type
                        case 1
                            fields = [{'Hm0t', 'R2p', 'R2p_SWL'}, overtopping];
                        case 2
                            fields = [{'p1'}, overtopping, {'p2dyn', 'p2sta', 'p2total', 'p3dyn', 'p3sta', 'p3total', 'pu',...
                                'X_low', 'X_up', 'X_c_surge', 'Bx', 'theta_low', 'theta_up', 'theta_center', 'Bjet', 'Vjet', 'Fjet'}];
                        case 3
                            fields = [{'Hm0t', 'R2p', 'R2p_SWL', 'Dn50', 'Dn50_LCBW', 'Dn50_Lee'}, overtopping];
                        otherwise
                            fields = [{'Hm0t', 'R2p', 'R2p_SWL', 'Dn50_LCBW'}, overtopping];
                    end
            end
        end

        function msg = results_status(app)
            % [NEW] Status Bar Text: Do The Loaded Results Match The Active PSE Geometry And The Current Run Tab Inputs? (No Side Effects)
            msg = '';
            try
                [pse_name, alt_name] = app.get_active_names();
                pseObj = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
                config_now = app.create_resp_config(pseObj, dbObj, false);
                saved_config = app.resultsState.config;
                % Geometry: PSE Geometry Fields (Bridged Into config; offshore_elevation As Resolved, Storm DB Grid Wins)
                geo_fields = fieldnames(pseObj);
                [geo_changed, geo_fld] = any_field_changed(config_now, saved_config, geo_fields);
                % Run Tab Inputs: Every Other Field (sim_inputs_changed Compares The Fields Of config_now)
                [inp_changed, inp_fld] = app.sim_inputs_changed(saved_config, rmfield(config_now, geo_fields));
                if geo_changed && inp_changed
                    msg = ['PSE geometry differs from the loaded results (' geo_fld '); Run tab inputs also changed (' inp_fld '). Press Run to update.'];
                elseif geo_changed
                    msg = ['PSE geometry differs from the loaded results (' geo_fld '). Press Run to update.'];
                elseif inp_changed
                    msg = ['Run tab inputs changed since the loaded results (' inp_fld '). Press Run to update.'];
                else
                    msg = app.loaded_sim_status();
                end
            catch
            end
        end

        function results_tab_resized(app)
            % [NEW] Results Tab SizeChangedFcn (Set In startupFcn): Redraw A Shown Multi-Panel Plot Only When It Starts Or
            % Stops Fitting The Tab (A Fitting Mirror Rescales With Its Grid), So Dragging The Window Does Not Redraw It
            row = app.results_plot_row();
            if isempty(app.resultsMirrorFits) || isempty(row) || ~row.multi_panel
                return
            end
            if app.results_mirror_fits(row) ~= app.resultsMirrorFits
                app.plot_project_responses();
            end
        end

        function plan = run_hotstart_plan(app, config, old)
            % [NEW] Run With A Saved Simulation: Library Hotstart Level (hotstart_plan) Of The Current Inputs (config, Not For Run)
            % Against The Saved Run (old). GUI Inputs The Library Lists Do Not Cover (DB Uncertainties, Region)
            % Count As 'resample'. plan.available: Hotstart Offered (Not 'resample', Needed Cached Forcing/Seed Present). plan.text: Dialog Line
            plan = struct('level', 'resample', 'reason', '', 'available', false, 'text', '', 'old', old);
            if ~all(isfield(old, {'u_engine', 'f_adjust'}))
                plan.text = 'Hotstart not available: the saved run has no hotstart state.';
                return
            end
            is_lcs = config.workflow == 3;
            why_not = '';
            gui_deps = {'region', 'chs_swl_u_a', 'chs_swl_u_r', 'chs_hm0_u_a', 'chs_hm0_u_r'};
            [changed, plan.reason] = any_field_changed(config, old, gui_deps);
            if ~changed
                [plan.level, plan.reason, why_not] = hotstart_plan(config, old, is_lcs);
            end
            if ~isempty(why_not)
                plan.text = sprintf('Hotstart not available: ''%s'' changed, but %s.', plan.reason, why_not);
            elseif strcmp(plan.level, 'resample')
                plan.text = sprintf('Hotstart not available: ''%s'' changed.', plan.reason);
            elseif strcmp(plan.level, 'full') && ~isfile(config.out_files.project_forcing)
                plan.text = 'Hotstart not available: the cached forcing is missing.';
            else
                plan.available = true;
                if strcmp(plan.level, 'reuse_sampling') && is_lcs
                    plan.text = sprintf('Hotstart keeps the sampled life cycles; forcing rebuilt because ''%s'' changed.', plan.reason);
                elseif strcmp(plan.level, 'reuse_sampling')
                    plan.text = sprintf('Hotstart rebuilds the forcing because ''%s'' changed.', plan.reason);
                elseif app.sim_inputs_changed(old, config)
                    plan.text = 'Hotstart reuses the cached forcing.';
                else
                    plan.text = 'Inputs are identical; a hotstart reproduces the saved results.';
                end
            end
        end

        function [choice, plan] = run_start_choice(app, pseObj, dbObj)
            % [NEW] Run With A Saved Simulation Of The Current Key: "Hotstart" (Hidden When Not Available) | "Coldstart" |
            % "Load existing" | "Cancel" ('Cancel' Also When The Dialog Is Closed)
            config = app.create_resp_config(pseObj, dbObj, false);
            S = load(config.out_files.config, 'config');
            S.config = app.localize_saved_config(S.config); % Saved tide_file In This Project Folder: Not A Change (Hotstart Dependency)
            plan = app.run_hotstart_plan(config, S.config);
            [~, label, run_date] = app.saved_sim();
            options = {'Hotstart', 'Coldstart', 'Load existing', 'Cancel'};
            if ~plan.available
                options = options(2:end);
            end
            app.auxLib.choice_dlgbox(sprintf('A simulation for %s already exists (run %s).\n%s', label, run_date, plan.text),...
                options, 'Existing Simulation', [560 170]);
            choice = app.auxLib.dialog_answer;
            if isempty(choice)
                choice = 'Cancel';
            end
        end

        function save_project(app, chess_project)
            % Save out Project state (Default: The Active Project)
            if nargin < 2
                if isempty(app.proj_indx)
                    return
                end
                chess_project = app.chess_projects(app.proj_indx); %#ok<ADPROP,*ADPROPLC>
            end
            save(fullfile(chess_project.path, chess_project.name, ['CHESS_project_' chess_project.name '.mat']), 'chess_project'); %#ok<ADPROP>
        end

        function [tf, label, run_date] = saved_sim(app)
            % [NEW] Saved Simulation For The Current Key (Config + Responses Files), "<alt> / <workflow> / <DB>", Run Date
            [config_file, ~, ~, wflow_name, out_files] = app.get_config_path();
            [~, alt_name] = app.get_active_names();
            tf = isfile(config_file) && isfile(out_files.resp_data);
            label = sprintf('%s / %s / %s', alt_name, wflow_name, app.StormDBDropDown_run.Value);
            run_date = '';
            if tf
                run_date = char(datetime(dir(out_files.resp_data).datenum, 'ConvertFrom', 'datenum', 'Format', 'yyyy-MM-dd HH:mm'));
            end
        end

        function set_active_selection(app, proj_name, pse_name, alt_name)
            % [NEW] Single Place That Resolves The Active Project/PSE/Alternative.
            % Unknown Or Empty Names Fall Back To The First Available Entry.
            app.wProj = [];
            app.proj_indx = [];
            app.pse_indx = [];
            app.alt_indx = [];
            if isempty(app.chess_projects)
                return
            end
            app.proj_indx = find(strcmp(proj_name, {app.chess_projects.name}), 1);
            if isempty(app.proj_indx)
                app.proj_indx = 1;
            end
            app.wProj = app.chess_projects(app.proj_indx);
            % PSE
            pseList = app.wProj.pse_list;
            if isempty(pseList)
                return
            end
            if ~any(strcmp(pse_name, pseList))
                pse_name = pseList{1};
            end
            app.wProj.set_working_pse(pse_name);
            app.pse_indx = app.wProj.pse_indx;
            % Alternative
            altList = app.wProj.alternative_list{app.pse_indx};
            if isempty(altList)
                return
            end
            if ~any(strcmp(alt_name, altList))
                alt_name = altList{1};
            end
            app.wProj.set_working_alt(pse_name, alt_name);
            app.alt_indx = app.wProj.alt_indx;
        end

        function set_dropdown_items(app, dd, items)
            % [NEW] Replace Dropdown Items, Keeping The Current Value When Still Available
            prev = dd.Value;
            items = cellstr(items(:)');
            if isempty(items)
                items = {'-'};
            end
            dd.Items = items;
            if ischar(prev) && any(strcmp(items, prev))
                dd.Value = prev;
            end
        end

        function set_input_table(app)
            % Light Update (No Tree Rebuild): Inputs Table Rows, Run Button, State Snapshot
            app.populate_inputs_table();
            % Run Needs A Response, A Data Type, And Workflow Inputs Defined For This PSE Type
            has_inputs = true;
            if app.WorkflowDropDown.Value == 3
                has_inputs = any(strcmp(app.WorkflowInputsTable.Data(:, 1), 'mcs_nLC'));
            end
            app.RunSimulationButton.Enable = has_inputs && ~isempty(app.ResponsesTree.CheckedNodes) &&...
                ~isempty(app.DataTypesTree.CheckedNodes);
            app.get_sim_state();
            % Status Bar Follows Run Tab Edits
            if ~isempty(app.resultsState)
                app.set_sim_status(app.results_status());
            end
        end

        function set_run_active(app, tf)
            % [NEW] Run In Progress Flag (RunSimulationButtonPushed Clears It On Every Exit Through onCleanup)
            app.runActive = tf;
        end

        function set_sim_state(app, opMode, config_file)
            % opMode: 1 - Snapshot (simState), 2 - Saved Config, 3 - Keep Current Inputs In A New Context (Snapshot Mapped To
            % The Active PSE's Library Template). Inputs Table Values Live In tableState (All Workflows), Table Shows The Pertinent Rows
            [pse_name, alt_name] = app.get_active_names();
            switch opMode
                case 1 % Snapshot (tableState Kept As Is)
                    restore_snapshot({});
                case 2 % With Config
                    S = load(config_file, 'config');
                    config = app.localize_saved_config(S.config);
                    set_value(app.StormDBDropDown_run, config.storm_db);
                    set_value(app.StormTypeDropDown_2, config.storm_sampling);
                    set_value(app.TidesFileDropDown, config.tide_file);
                    app.ApplyDepthLimitationCheckBox.Value = logical(config.apply_depth_limitation);
                    app.SeaLevelChangeEditField.Value = config.swl_slr;
                    % Responses: Checked Where Config Switch Is On
                    resp = app.get_tree_symbols(app.ResponsesTree);
                    set_checked(app.ResponsesTree, resp(cellfun(@(x) isfield(config, x) && config.(x) == 1, resp)));
                    % Data Types
                    dt = {};
                    if config.use_peaks == 1, dt = [dt, {'peaks_default'}]; end
                    if config.create_wlp == 1, dt = [dt, {'create_wlp'}]; end
                    if config.create_whp == 1, dt = [dt, {'create_whp'}]; end
                    if config.use_timeseries == 1, dt = [dt, {'use_timeseries'}]; end
                    set_checked(app.DataTypesTree, dt);
                    % Inputs Of Every Workflow From Config
                    pse_geo = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                    tpl = app.wProj.get_library_template(pse_geo.pse_type);
                    is_param = ~startsWith(tpl.responses(:, 2), 'Comput');
                    inputs = [{'storm_duration'}; tpl.responses(is_param, 1); tpl.mcs(:, 1); tpl.csr(:, 1); tpl.pros(:, 1)];
                    app.tableState = struct();
                    app.WorkflowInputsTable.Data = cell(0, 3); % Else populate_inputs_table Stores The Visible (Previous) Values Over The Config's
                    for kk = 1:length(inputs)
                        if isfield(config, inputs{kk})
                            app.tableState.(inputs{kk}) = config.(inputs{kk});
                        end
                    end
                case 3 % Keep Current Inputs: Snapshot Values Where The Template Has The Response/Row (PSE Type Can Differ),
                    % Template Defaults Only For Responses, Data Types And Rows The Previous State Lacked
                    restore_snapshot({'StormDBDropDown_run'}); % New Context: Storm DB Already Chosen (populateSimulationTab)
                    pse_geo = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                    tpl = app.wProj.get_library_template(pse_geo.pse_type);
                    on = tpl.responses(cellfun(@(x) isequal(x, 1), tpl.responses(:, 4)), 1);
                    set_checked(app.ResponsesTree, [app.get_checked_symbols(app.ResponsesTree), setdiff(on(:)', snapshot_value('ResponseSymbols'))]);
                    gen = tpl.general;
                    is_on = @(s) any(strcmp(gen(:, 1), s)) && isequal(gen{strcmp(gen(:, 1), s), 4}, 1);
                    dt = {};
                    if is_on('use_peaks'), dt = [dt, {'peaks_default'}]; end
                    if is_on('create_wlp'), dt = [dt, {'create_wlp'}]; end
                    if is_on('create_whp'), dt = [dt, {'create_whp'}]; end
                    if is_on('use_timeseries'), dt = [dt, {'use_timeseries'}]; end
                    set_checked(app.DataTypesTree, [app.get_checked_symbols(app.DataTypesTree), setdiff(dt, snapshot_value('DataTypeSymbols'))]);
                    % Inputs: Visible Values Stored First, Then Only The Rows Of This Template Are Kept
                    app.store_table_values();
                    is_param = ~startsWith(tpl.responses(:, 2), 'Comput');
                    inputs = [{'storm_duration'}; tpl.responses(is_param, 1); tpl.mcs(:, 1); tpl.csr(:, 1); tpl.pros(:, 1)];
                    drop = setdiff(fieldnames(app.tableState), inputs);
                    if ~isempty(drop)
                        app.tableState = rmfield(app.tableState, drop);
                    end
                    app.WorkflowInputsTable.Data = cell(0, 3);
            end
            % Rebuild Inputs Table For Restored Checks & Workflow
            app.populate_inputs_table();

            function restore_snapshot(skip)
                % Run Tab Controls From simState (Component Name, Value), Except skip
                winState = app.simState;
                for ii = 1:size(winState, 1)
                    switch winState{ii, 1}
                        case [{'ResponseSymbols', 'DataTypeSymbols'}, skip]
                        case {'ResponsesTree', 'DataTypesTree'}
                            set_checked(app.(winState{ii, 1}), winState{ii, 2});
                        otherwise
                            if ~isempty(winState{ii, 2}) && ~any(strcmp(winState{ii, 2}, {'-','None'}))
                                set_value(app.(winState{ii, 1}), winState{ii, 2});
                            end
                    end
                end
            end
            function v = snapshot_value(name)
                % simState Entry ({} If Absent)
                v = {};
                if ~isempty(app.simState) && any(strcmp(app.simState(:, 1), name))
                    v = app.simState{strcmp(app.simState(:, 1), name), 2};
                end
            end

            function set_checked(tree, symbols)
                % Check Nodes (Any Level) Whose NodeData Is In symbols
                nodes = app.get_tree_nodes(tree);
                if ~isempty(nodes) && ~isempty(symbols)
                    nodes = nodes(ismember({nodes.NodeData}, symbols));
                end
                if isempty(nodes) || isempty(symbols)
                    tree.CheckedNodes = []; % R2022b Rejects An Empty TreeNode Array (No Remembered Node In The Rebuilt Tree)
                else
                    tree.CheckedNodes = nodes;
                end
            end
            function set_value(comp, value)
                % Dropdowns: Set Only If Available (ItemsData When Defined). Other Components: Set Directly
                if isprop(comp, 'Items')
                    if iscell(comp.ItemsData) && ~isempty(comp.ItemsData) % e.g. Tide Files (Paths)
                        valid = any(cellfun(@(x) isequal(x, value), comp.ItemsData));
                    elseif ~isempty(comp.ItemsData)
                        valid = isnumeric(value) && ismember(value, comp.ItemsData);
                    else
                        valid = any(strcmp(comp.Items, value));
                    end
                    if valid
                        comp.Value = value;
                    end
                else
                    comp.Value = value;
                end
            end
        end

        function set_sim_status(app, msg)
            % [NEW] Run Tab Status Bar (Display Only)
            app.simStatusBar.Value = msg;
        end

        function show_popout_note(app, row)
            % [NEW] Multi-Panel Results Plot Not Drawn In The Tab: Grey Out The Tab Axes With A Note (+ Resize Hint If The
            % Plot Can Be Mirrored In A Bigger Window)
            ax = app.PSEAxes_3;
            ax.Color = [0.92 0.92 0.92];
            ax.XTick = [];
            ax.YTick = [];
            note = {'Multi-panel plot', 'Shown in the pop-out window'};
            if all(isfinite(app.results_mirror_min_size(row.key)))
                note{end + 1} = 'Enlarge the CHESS window to show it here';
            end
            text(ax, 0.5, 0.5, note, 'Units', 'normalized', 'HorizontalAlignment', 'center',...
                'FontSize', app.plotFontSize, 'Color', [0.45 0.45 0.45], 'ContextMenu', app.resultsMenu);
        end

        function [elev, source] = shown_offshore_elevation(app, dbObj)
            % [NEW] Offshore Elevation Shown On The Run Tab And In The PSE Pop-Out: Storm DB Study Grid Value (Wins, As In
            % create_resp_config), Else The Active PSE's Geometry Value (Fallback Entered At PSE Creation), Else NaN. dbObj
            % Empty (No Run Tab Storm DB): The PSE Value
            elev = [];
            if ~isempty(dbObj)
                elev = app.db_offshore_elevation(dbObj);
            end
            source = 'Storm DB grid';
            if isempty(elev)
                elev = NaN;
                source = '';
                [pse_name, alt_name] = app.get_active_names();
                pse_geo = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                if isstruct(pse_geo) && isfield(pse_geo, 'offshore_elevation') && ~isempty(pse_geo.offshore_elevation)
                    elev = pse_geo.offshore_elevation;
                    source = 'PSE geometry';
                end
            end
        end

        function bk = sim_backup_paths(app, out_files)
            % [NEW] Run Files Of A Slot A Run Overwrites (Saved Config, Project Forcing, LCS Sampling Seed) And Their Backups
            % While The Run Lasts (Same Folder: <file>_prev.mat). bk(k).name: 'config' | 'forcing' | 'seed'
            files = {out_files.config, out_files.project_forcing, lcs_sampling_seed_file(out_files.project_forcing)};
            prev = cellfun(@(f) [f(1:end-4) '_prev.mat'], files, 'un', false);
            bk = struct('name', {'config', 'forcing', 'seed'}, 'file', files, 'prev', prev);
        end

        function [changed, fld] = sim_inputs_changed(app, saved_config, config_now)
            % [NEW] Current Run Tab Inputs Vs A Saved Run's config (Library any_field_changed, No Side Effects)
            if nargin < 3
                [pse_name, alt_name] = app.get_active_names();
                pseObj = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
                config_now = app.create_resp_config(pseObj, dbObj, false);
            end
            % outfolder: Project Location (Moves With An Imported/Relocated Project), Not A Run Input
            ignore = {'u_engine', 'f_adjust', 'out_files', 'in_files', 'outfolder'};
            [changed, fld] = any_field_changed(config_now, saved_config, setdiff(fieldnames(config_now), ignore));
        end

        function key = sim_key(app)
            % [NEW] Saved Simulation Key: Config File Of Alternative + Workflow + Run Tab Storm DB ('' Without Both)
            key = '';
            if app.has_active_alt() && ~isempty(app.StormDBDropDown_run.Items)
                key = app.get_config_path();
            end
        end

        function store_table_values(app)
            % [NEW] Save Visible Inputs Table Values Into tableState
            if ~isstruct(app.tableState)
                app.tableState = struct();
            end
            tbl = app.WorkflowInputsTable.Data;
            for kk = 1:size(tbl, 1)
                app.tableState.(tbl{kk, 1}) = tbl{kk, 3};
            end
        end

        function ok = storm_db_info_ok(app, dbObj)
            % [NEW] Storm DB Has Its db_info (Library storm_db_info 'valid'); Imported With An Older CHESS Version: Alert (Must
            % Be Re-Imported) Once Per Storm DB Per Session, Callers Still Block Silently Afterwards. The Run Path Keeps Its Own
            % Error (dbObj.get_db_info In create_resp_config, Run-Failed Dialog)
            ok = storm_db_info('valid', dbObj.db_info);
            if ~ok && ~any(strcmp(app.oldDbWarned, dbObj.output_paths.storm_db))
                app.oldDbWarned{end+1} = dbObj.output_paths.storm_db;
                uialert(app.UIFigure, 'This storm database was built with an older CHESS version and must be re-imported.',...
                    ['Storm DB: ' dbObj.db_name], 'Icon', 'warning');
            end
        end

        function sync_saved_sim(app, ask)
            % [NEW] After A Switch Of Alternative / Workflow / Run Tab Storm DB (Key Changed), A Saved Simulation Of The New Key Is
            % Loaded Silently When The Current Inputs Match It, Or Without ask (Internal Refresh). Otherwise ask:
            % "Load" | "Keep current inputs" (Or Closed: Inputs Kept, Results Tab Cleared). No Saved Simulation: Inputs Kept.
            % Run Tab Storm DB Of An Older CHESS Version (No db_info, Inputs Cannot Be Compared): Alert, Then Ask As Changed Inputs.
            % The Callers' populateResultsTab Loads Nothing While The Key Differs, So Results Appear Only Once Resolved Here.
            % A Declined Load Is Remembered (declinedSimKey) Until The Key Changes: Switching Back Asks Again
            key = app.sim_key();
            if isequal(key, app.simKey)
                return
            end
            app.simKey = key;
            app.declinedSimKey = '';
            if isempty(key)
                return
            end
            [has_sim, label] = app.saved_sim();
            if ~has_sim
                app.set_sim_status(['No saved simulation for ' label '.']);
                return
            end
            S = load(key, 'config');
            S.config = app.localize_saved_config(S.config);
            load_it = ~ask;
            if ask
                dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
                load_it = app.storm_db_info_ok(dbObj) && ~app.sim_inputs_changed(S.config);
            end
            if ~load_it
                app.auxLib.choice_dlgbox(['A simulation for ' label ' already exists. Load it?'], {'Load', 'Keep current inputs'},...
                    'Saved Simulation Found');
                load_it = isequal(app.auxLib.dialog_answer, 'Load');
            end
            if load_it
                app.load_saved_sim();
            else
                app.declinedSimKey = key;
                app.resultsState = [];
                app.reset_tab('results');
                app.set_sim_status('Current simulation already exists. Load declined.');
            end
        end

        function update_project_tree(app)
            % Clear Project Tree (And The Nodes' Right-Click Menus, Which Belong To The Figure)
            delete(app.ProjectTree.Children);
            delete(findall(app.UIFigure, 'Type', 'uicontextmenu', 'Tag', 'ProjectTreeNodeMenu'));
            act_node = [];
            for kk = 1:length(app.chess_projects)
                wObj = app.chess_projects(kk);
                % Create Project Node
                proj_node = uitreenode(app.ProjectTree, 'Text', wObj.name,...
                    'NodeData', struct('type', 'proj'));
                add_rename_menu(proj_node);
                if kk == app.proj_indx
                    act_node = proj_node;
                end
                % Loop Across PSEs (Read Lists Directly, No Working Index Side Effects)
                for jj = 1:length(wObj.pse_list)
                    pse_node = uitreenode(proj_node, 'Text', wObj.pse_list{jj},...
                        'NodeData', struct('type', 'pse'));
                    add_rename_menu(pse_node);
                    if kk == app.proj_indx && isequal(jj, app.pse_indx)
                        act_node = pse_node;
                    end
                    % Loop Across Alternatives
                    altList = wObj.alternative_list{jj};
                    for ii = 1:length(altList)
                        alt_node = uitreenode(pse_node, 'Text', altList{ii},...
                            'NodeData', struct('type', 'alt'));
                        add_rename_menu(alt_node);
                        if kk == app.proj_indx && isequal(jj, app.pse_indx) && isequal(ii, app.alt_indx)
                            act_node = alt_node;
                        end
                    end
                end
            end
            % Highlight Active Node
            if ~isempty(act_node)
                expand(app.ProjectTree, 'all');
                app.ProjectTree.SelectedNodes = act_node;
            end

            function add_rename_menu(node)
                % One Menu Per Node: R2022b ContextMenuOpeningFcn Event Data Does Not Say Which Tree Node Was Right-Clicked
                cm = uicontextmenu(app.UIFigure, 'Tag', 'ProjectTreeNodeMenu');
                uimenu(cm, 'Text', 'Rename...', 'MenuSelectedFcn', @(~, ~) app.rename_tree_node(node));
                node.ContextMenu = cm;
            end
        end

        function update_results_controls(app, level)
            % [NEW] Results Dropdowns Follow The Selected Plot Type (Library Method Inputs).
            %   Data/Match/Storm Type -> ds_query | Field To Plot -> Response (Or Relabeled Option) | Storm ID -> Event, Life Cycle Or Option
            %   level: 'plot' | 'data' | 'match' | 'storm' | 'field' (Control That Changed; Dependent Controls Below It Refresh)
            row = app.results_plot_row();
            if isempty(row)
                return
            end
            Resp = app.resultsState.Resp;
            lvl = find(strcmp(level, {'plot', 'data', 'match', 'storm', 'field'}));
            if lvl <= 1
                app.set_dropdown_items(app.DataTypeDropDown_results, row.data_types);
            end
            dt = app.DataTypeDropDown_results.Value;
            if lvl <= 2
                app.set_dropdown_items(app.MatchTypeDropDown_results, fieldnames(Resp.(dt)));
            end
            mt = app.MatchTypeDropDown_results.Value;
            if lvl <= 3
                st_list = fieldnames(Resp.(dt).(mt))';
                if strcmp(row.key, 'event') % Event Viewer Reads The Storm DB (XC/TC Only; CC Is Derived)
                    storm = app.load_storm(app.resultsState.dbObj);
                    st_list = st_list(cellfun(@(s) ~strcmp(s, 'CC') && isfield(storm, s) && isfield(storm.(s), dt) &&...
                        isfield(storm.(s).(dt), mt), st_list));
                elseif row.needs_s % Damage Plots: Storm Types Whose Results Hold S (Same Test As results_field_items)
                    st_list = st_list(cellfun(@(s) isstruct(Resp.(dt).(mt).(s)) && isfield(Resp.(dt).(mt).(s), 'S'), st_list));
                end
                app.set_dropdown_items(app.StormTypeDropDown_results, st_list);
            end
            st = app.StormTypeDropDown_results.Value;
            if ~isfield(Resp.(dt).(mt), st) % '-': No Storm Type For This Plot (plot_project_responses Draws Nothing)
                app.set_dropdown_items(app.FieldToPlotDropDown_results, {});
                app.FieldToPlotDropDown_results.Enable = false;
                app.set_dropdown_items(app.StormIDDropDown_results, {});
                app.StormIDDropDown_results.Enable = false;
                return
            end
            data = Resp.(dt).(mt).(st);
            ds_query = struct('storm_type', st, 'data_type', dt, 'match_type', mt);
            % Field To Plot
            if lvl <= 4
                [f_lbl, f_items] = app.results_field_items(row.key, data);
                app.FieldToPlotDropDown_2Label.Text = f_lbl;
                app.set_dropdown_items(app.FieldToPlotDropDown_results, f_items);
                app.FieldToPlotDropDown_results.Enable = ~isempty(f_items);
            end
            % Storm ID Dropdown (Event, Life Cycle Or Option)
            [a_lbl, a_items, a_on] = app.results_aux_items(row.key, data, ds_query);
            app.StormIDDropDown_2Label.Text = a_lbl;
            app.set_dropdown_items(app.StormIDDropDown_results, a_items);
            app.StormIDDropDown_results.Enable = a_on;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize Aux Library Class
            app.auxLib = auxUIFunctions();
            app.auxLib.set_parent(app.UIFigure); % auxLib Dialogs Open Centered On The CHESS Window
            % CHESS_Library Path: The App Lives In CHESS_Library\GUI, So The Library Is Its Parent Folder
            app_dir = fileparts(mfilename('fullpath'));
            lib_dir = fileparts(app_dir);
            if ~isfile(fullfile(lib_dir, 'Setup', 'create_structure_geometry.m'))
                app.auxLib.error_dlgbox(['CHESS_Library not found. The app must be located in CHESS_Library' filesep 'GUI.']);
            else
                addpath(genpath(lib_dir));
            end
            app.stormCache = containers.Map();
            app.tableState = struct();
            % Constants Table (Columns Sized To Their Text; No Project Yet: '-' Rows)
            app.ConstantsTable.ColumnWidth = {'fit', 'fit', 'fit'};
            app.populate_constants_table();
            % Results Tab (Created In Code, No Design View Changes): Dropdown Callbacks, Right-Click Plot Options
            set([app.PlotTypeDropDown, app.StormTypeDropDown_results, app.DataTypeDropDown_results,...
                app.MatchTypeDropDown_results, app.FieldToPlotDropDown_results, app.StormIDDropDown_results],...
                'ValueChangedFcn', @(src, ~) app.results_control_changed(src));
            app.resultsMenu = uicontextmenu(app.UIFigure);
            uimenu(app.resultsMenu, 'Text', 'Plot Options...', 'MenuSelectedFcn', @(~, ~) app.results_plot_options_dialog());
            app.PSEAxes_3.ContextMenu = app.resultsMenu;
            app.resultsPlotOptions = struct();
            % Multi-Panel Results Plots Mirrored In The Tab (Same Grid Cell As PSEAxes_3) When It Is Big Enough; Fit Re-Checked
            % When The Results Tab Is Resized (SizeChangedFcn Needs AutoResizeChildren Off; The Tab's Only Child Is A Grid)
            app.resultsMirror = uipanel(app.StormDBViewerGrid_2, 'BorderType', 'none', 'Visible', 'off');
            app.resultsMirror.Layout.Row = 1;
            app.resultsMirror.Layout.Column = 1;
            app.ResultsViewerTab.AutoResizeChildren = 'off';
            app.ResultsViewerTab.SizeChangedFcn = @(~, ~) app.results_tab_resized();
            % Empty State
            app.refresh_active_view();
        end

        % Menu selected function: CreateNewProjectMenu
        function CreateNewProjectMenuSelected(app, event)
            % Call Project Creation UI (Loaded Project Names: A New Project Cannot Take One)
            proj_names = {};
            if ~isempty(app.chess_projects)
                proj_names = {app.chess_projects.name};
            end
            obj = ProjectCreationUI(app.UIFigure, proj_names);
            % Wait For UI Figure To Close
            uiwait(obj.uifig);
            % If User Submited Project, Go Ahead And Populate UI
            if obj.project_created
                app.append_project(obj.chess_project);
                % Save Project State
                app.save_project();
                % Long Project Folder Path (Windows 260-Character Limit): Warn, Project Kept
                if ~isempty(obj.path_warning)
                    uialert(app.UIFigure, obj.path_warning, 'Long Project Path', 'Icon', 'warning');
                end
            end
        end

        % Menu selected function: ImportProjectMenu
        function LoadProjectMenuSelected(app, event)
            % [NEW] Prompt User: Project File (CHESS_project_<name>.mat In Its Project Folder) Or Exported Project Archive (.zip)
            [file, location] = uigetfile({'*.mat;*.zip', 'CHESS Project (*.mat, *.zip)'}, 'Import CHESS Project', '');
            if isequal(file, 0)
                return
            end
            [~, ~, ext] = fileparts(file);
            if strcmpi(ext, '.zip')
                app.extract_project_zip(fullfile(location, file));
            else
                app.import_project_mat(fullfile(location, file));
            end
        end

        % Menu selected function: ExportProjectMenu
        function ExportProjectMenuSelected(app, event)
            if isempty(app.wProj)
                return
            end
            % Wait For User To Define Export Location And Name
            [file, fPath] = uiputfile({'.zip'}, 'Export CHESS Project', ['CHESS_project_'  app.wProj.name '_archive.zip']);
            % Export Project
            if ~all(file == 0)
                % Save out Project state
                app.save_project();
                % Create Project Archive: <name>/ + The Region's CHS_Dependencies Files (chessProject.export_archive)
                try
                    note = app.wProj.export_archive(fullfile(fPath, file));
                catch ME
                    app.auxLib.error_dlgbox(['Could not export the project: ' ME.message]);
                    return
                end
                app.set_sim_status(note);
            end
        end

        % Selection changed function: ProjectTree
        function ProjectTree_SelectionChanged(app, event)
            % Resolve Clicked Node To Project/PSE/Alternative Names
            [proj_node, pse_node, alt_node] = app.get_node_family();
            pse_name = '';
            alt_name = '';
            if ~isempty(pse_node)
                pse_name = pse_node.Text;
            end
            if ~isempty(alt_node)
                alt_name = alt_node.Text;
            end
            app.set_active_selection(proj_node.Text, pse_name, alt_name);
            app.refresh_active_view(true);
        end

        % Menu selected function: FloodwallMenu, LeveeMenu, 
        % ...and 2 other components
        function PSECreationMenuSelected(app, event)
            if isempty(app.wProj)
                return
            end
            % Call PSE Creation UI
            obj = PSECreationUI(event.Source.Text, app.wProj, '', app.UIFigure);
            uiwait(obj.uifig);
            if obj.pse_created
                app.set_active_selection(app.wProj.name, obj.pse_name, obj.alt_name);
                app.project_field_ini();
                app.refresh_active_view();
                app.save_project();
            else % Remove In-Progress PSE (Keeps All Per-PSE Fields Aligned)
                app.wProj.remove_pse(obj.pse_name);
                app.set_active_selection(app.wProj.name, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            end
        end

        % Menu selected function: AlternativeMenu
        function AlternativeMenuSelected(app, event)
            % Get PSE List For Active Project
            pseList = app.wProj.get_project_field('pse_list', '', '', 'proj');
            % Ask User For PSE Template
            app.auxLib.dropdown_input_dlgbox('Choose PSE to use as template for alternative.', pseList, 'Add New Alternative To Project PSE', 'OK');
            if isempty(app.auxLib.dialog_answer)
                return
            end
            pse_geo = app.wProj.get_project_field('pse_geometry', app.auxLib.dialog_answer, '', 'pse');
            % Call PSE Creation UI (PSE Type From First Alternative)
            obj = PSECreationUI(pse_geo{1}.pse_type, app.wProj, app.auxLib.dialog_answer, app.UIFigure);
            uiwait(obj.uifig);
            if obj.pse_created
                app.set_active_selection(app.wProj.name, obj.pse_name, obj.alt_name);
                app.project_field_ini();
                app.refresh_active_view();
                app.save_project();
            else % Remove In-Progress Alternative
                app.wProj.remove_alternative(obj.pse_name, obj.alt_name);
                app.set_active_selection(app.wProj.name, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            end
        end

        % Menu selected function: RemoveAlternativeMenu, RemovePSEMenu, 
        % ...and 1 other component
        function RemoveProjectElem(app, event)
            if isempty(app.wProj)
                return
            end
            % Not While A Run Writes Into The Project Folders
            if app.runActive
                uialert(app.UIFigure, 'Removing is not available while a run is in progress.', 'Project Management: Remove', 'Icon', 'info');
                return
            end
            % PSE / Alternative: Confirmed, Then Its Folder Deleted (chessProject.delete_folder: Nothing Changes If That Fails)
            leftover = '';
            switch event.Source.Text
                case 'PSE'
                    choices = app.wProj.get_project_field('pse_list', '', '', 'proj');
                    if isempty(choices)
                        return
                    end
                    app.auxLib.dropdown_input_dlgbox(['Remove PSE from project ' app.wProj.name '.'], choices, 'Project Management: Remove');
                    pse_name = app.auxLib.dialog_answer;
                    if isempty(pse_name)
                        return
                    end
                    app.auxLib.confirmation_dlgbox(sprintf('Remove PSE "%s" and delete its folder (all alternatives and run results)?',...
                        pse_name), 'Remove', 'Remove PSE');
                    if ~app.auxLib.dialog_answer
                        return
                    end
                    try
                        leftover = app.wProj.remove_pse(pse_name, true);
                    catch ME
                        app.auxLib.error_dlgbox(['Could not remove PSE "' pse_name '": ' ME.message]);
                        return
                    end
                    app.resultsState = [];
                case 'Alternative'
                    choices = app.wProj.get_project_field('pse_list', '', '', 'proj');
                    if isempty(choices)
                        return
                    end
                    app.auxLib.dropdown_input_dlgbox('Choose PSE.', choices, 'Project Management: Remove');
                    pse_name = app.auxLib.dialog_answer;
                    if isempty(pse_name)
                        return
                    end
                    choices = app.wProj.get_project_field('alternative_list', pse_name, '', 'pse');
                    app.auxLib.dropdown_input_dlgbox(['Remove alternative from PSE ' pse_name '.'], choices, 'Project Management: Remove');
                    alt_name = app.auxLib.dialog_answer;
                    if isempty(alt_name)
                        return
                    end
                    % Last Alternative: The PSE Goes With It (chessProject.remove_alternative)
                    if numel(choices) == 1
                        prompt = sprintf(['"%s" is the last alternative of PSE "%s", so the PSE is removed too.\n',...
                            'Remove PSE "%s" and delete its folder (all alternatives and run results)?'], alt_name, pse_name, pse_name);
                    else
                        prompt = sprintf('Remove alternative "%s" of PSE "%s" and delete its folder (all run results)?', alt_name, pse_name);
                    end
                    app.auxLib.confirmation_dlgbox(prompt, 'Remove', 'Remove Alternative');
                    if ~app.auxLib.dialog_answer
                        return
                    end
                    try
                        leftover = app.wProj.remove_alternative(pse_name, alt_name, true);
                    catch ME
                        app.auxLib.error_dlgbox(['Could not remove alternative "' alt_name '": ' ME.message]);
                        return
                    end
                    app.resultsState = [];
                case 'Tides'
                    tide_files = app.wProj.get_project_field('tide_files_list', '', '', 'proj');
                    if isempty(tide_files)
                        return
                    end
                    [~, tide_names, tide_ext] = cellfun(@fileparts, tide_files, 'un', false); % Show File Names
                    choices = strcat(tide_names, tide_ext);
                    app.auxLib.dropdown_input_dlgbox(['Remove tide file from project ' app.wProj.name '.'], choices, 'Project Management: Remove');
                    if isempty(app.auxLib.dialog_answer)
                        return
                    end
                    app.wProj.tide_files_list(strcmp(choices, app.auxLib.dialog_answer)) = [];
            end
            % Reselect & Refresh
            app.set_active_selection(app.wProj.name, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            app.refresh_active_view();
            app.save_project();
            if ~isempty(leftover)
                uialert(app.UIFigure, sprintf('Removed, but this folder could not be fully deleted (a file inside may be open):\n%s\nDelete it manually.',...
                    leftover), 'Project Management: Remove', 'Icon', 'warning');
            end
        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            app.set_active_selection(app.ProjectDropDown.Value, '', '');
            app.refresh_active_view(true);
        end

        % Value changed function: PSEDropDown
        function PSEDropDownValueChanged(app, event)
            app.set_active_selection(app.ProjectDropDown.Value, app.PSEDropDown.Value, '');
            app.refresh_active_view(true);
        end

        % Value changed function: AlternativeDropDown
        function AlternativeDropDownValueChanged(app, event)
            app.set_active_selection(app.ProjectDropDown.Value, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            app.refresh_active_view(true);
        end

        % Button pushed function: Button_5, ExtrudeTableButton, 
        % ...and 1 other component
        function ExtrudeTableButtonPushed(app, event)
            % The Tab's Table (One Per Tab; The Button Sits In A Nested Grid, Not On The Tab)
            tab = ancestor(event.Source, 'matlab.ui.container.Tab');
            tbl = findobj(tab, '-isa', 'matlab.ui.control.Table');
            % Create new uifigure
            newFig = uifigure("WindowStyle","normal");
            % Create 1x1 grid layout
            gl = uigridlayout(newFig, [1, 1]);
            gl.RowHeight = {'1x'};
            gl.ColumnWidth = {'fit'};
            % Copy table properties
            newTbl = uitable(gl);
            newTbl.Data = tbl.Data;
            newTbl.ColumnName = tbl.ColumnName;
            newTbl.RowName = tbl.RowName;
            newTbl.ColumnEditable = tbl.ColumnEditable;
            if any(ismember(newTbl.ColumnName, 'model_var'))
                newTbl.ColumnName(ismember(newTbl.ColumnName, 'model_var'))=[];
            end
            newFig.Position(3) = newFig.Position(3)+25;
        end

        % Button pushed function: Button_6, ExtrudeFigureButton, 
        % ...and 1 other component
        function ExtrudeFigureButtonPushed(app, event)
            % Pop-Out: Redraw The Tab's Plot With Its CHESS-Library Plot Method In A Classic Figure
            base_fnt = app.plotFontSize; % Same Base Font As The Tab Plots
            tab = ancestor(event.Source, 'matlab.ui.container.Tab'); % Button Sits In A Nested Grid, Not On The Tab
            % Nothing To Plot -> Inform User (No Empty Figure)
            if isequal(tab, app.PSEViewerTab)
                ready = app.has_active_alt();
            elseif isequal(tab, app.StormDBViewerTab)
                ready = ~isempty(app.StormDBDropDown.Items);
            elseif isequal(tab, app.ResultsViewerTab)
                ready = ~isempty(app.resultsState) && ~strcmp(app.StormTypeDropDown_results.Value, '-');
            else
                ready = false;
            end
            if ~ready
                uialert(app.UIFigure, 'There is nothing to plot on this tab yet.', 'Nothing To Plot', 'Icon', 'info');
                return
            end
            % Tab Plot Method In A Stand-Alone Classic Figure
            if isequal(tab, app.PSEViewerTab) % PSE Geometry (With Offshore Slope/Elevation Text Box)
                [pse_name, alt_name] = app.get_active_names();
                [offshore_elevation, elevation_source] = app.pse_popout_offshore_elevation();
                app.wProj.plot_geometry(pse_name, alt_name, axes('Parent', figure()), base_fnt, true, offshore_elevation, elevation_source);
            elseif isequal(tab, app.StormDBViewerTab) % Peaks: Data Space | Timeseries: Event Hydrograph
                if app.storm_db_info_ok(app.wProj.get_project_field('stormDB_list', app.StormDBDropDown.Value, '', 'db'))
                    app.plot_storm_db(axes('Parent', figure()), base_fnt);
                end
            else % Results: Selected Plot Type (Multi-Panel Plots Reuse The Results Pop-Out)
                row = app.results_plot_row();
                if ~isempty(row) && row.multi_panel
                    app.open_results_popout();
                else
                    app.plot_project_responses(figure(), base_fnt);
                end
            end

        end

        % Menu selected function: ImportCHSStudyHDF5Menu
        function CHSDatabaseMenu(app, event)
            if isempty(app.wProj)
                return
            end
            % Initialize CHS Library (The Project's CHS_Dependencies And Region: The Wizard Keeps Them). The Project's Storm DB
            % Names And Their Usage (Alternatives, Run Files): A Clash Is An Overwrite, Confirmed In The Wizard At Import
            paths = chess_paths(app.wProj.path, app.wProj.name);
            db_list = {app.wProj.stormDB_list.db_name};
            dbObj = CHSStormDBImportUI(app.wProj.chs_dependencies, paths.event_db_dir,...
                app.pull_input_table_value(app.ConstantsTable.Data, 'Gravity Constant'), app.wProj.vdatum, app.wProj.unitsys,...
                app.UIFigure, app.wProj.project_region(), db_list,...
                @(name) [app.wProj.stormdb_usage(name), numel(app.wProj.stormdb_run_files(name))]);
            % Call import Wizard
            dbObj.create_ui();
            uiwait(dbObj.figUI); % Wait for uifigure to close
            if ~dbObj.db_created
                return
            end
            indx = find(strcmpi(dbObj.db_name, db_list), 1);
            if dbObj.overwrite_flag && ~isempty(indx)
                % Overwrite Of A Project Storm DB (Index Kept): Its Run Files Deleted And Its Alternatives Unlinked (Saved
                % Simulations Are File Based, Old Results Would Otherwise Show As Saved); Session State Of The Old DB Dropped
                old = app.wProj.stormDB_list(indx);
                failed = {};
                for ff = app.wProj.stormdb_run_files(old.db_name)'
                    delete(ff{1});
                    if isfile(ff{1})
                        failed{end+1} = ff{1}; %#ok<AGROW>
                    end
                end
                app.wProj.clear_stormdb_links(old.db_name);
                app.wProj.stormDB_list(indx) = dbObj;
                db_files = {dbObj.output_paths.storm_db, fullfile(paths.event_db_dir, [dbObj.db_name '_raw_files.mat'])};
                if isstruct(old.output_paths)
                    db_files = [db_files, struct2cell(old.output_paths)'];
                end
                keys = app.stormCache.keys();
                stale = keys(ismember(lower(keys), lower(db_files)));
                if ~isempty(stale)
                    remove(app.stormCache, stale);
                end
                app.oldDbWarned = app.oldDbWarned(~ismember(lower(app.oldDbWarned), lower(db_files)));
                app.simKey = [];
                app.declinedSimKey = '';
                app.resultsState = [];
                if ~isempty(failed)
                    uialert(app.UIFigure, sprintf('Storm DB "%s" overwritten, but these run files could not be deleted:\n%s',...
                        dbObj.db_name, strjoin(failed, newline)), 'Storm DB Overwrite', 'Icon', 'warning');
                end
            else
                app.wProj.add_project_field('stormDB_list', dbObj, '', '', 'pse');
            end
            % Project CHS_Dependencies: Set By The First CHS (Non-Custom) Storm DB, Or Re-Pointed When The Stored Folder Is No
            % Longer Valid; Cleared When An Overwrite Left No CHS Storm DB
            region = app.wProj.project_region();
            if isempty(region)
                app.wProj.chs_dependencies = '';
            elseif ~strcmp(dbObj.study, 'Custom_Modeling') && ~isempty(chessProject.chs_dependencies_problem(app.wProj.chs_dependencies, region))
                app.wProj.chs_dependencies = dbObj.source_paths.chs_dependencies_path;
            end
            % Refresh UI & Show New DB On Storm DB Viewer
            app.refresh_active_view();
            app.populate_stormDB_panel(dbObj, {app.wProj.get_project_field('stormDB_list','','','proj').db_name});
            % Save out Project state
            app.save_project();
        end

        % Value changed function: FieldToPlotDropDown, MatchTypeDropDown, 
        % ...and 1 other component
        function updateStormDBPlot(app, event)
            % Match Type Changes Storm ID List: Refresh Panel (Which Replots)
            if ~isempty(event) && event.Source == app.MatchTypeDropDown
                app.StormDBDropDown_dbValueChanged(event);
                return
            end
            % Draw On Storm DB Tab (Axes + Table)
            app.plot_storm_db();
        end

        % Value changed function: StormDBDropDown
        function StormDBDropDown_dbValueChanged(app, event)
            %  Repopulate Storm DB Viewer Cascade For Selected DB
            dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown.Value, '', 'db');
            app.populate_stormDB_panel(dbObj, app.StormDBDropDown.Items);
        end

        % Value changed function: StormTypeDropDown
        function StormTypeDropDown_dbOpening(app, event)
            app.StormDBDropDown_dbValueChanged(event);
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDown_dbOpening(app, event)
            app.StormDBDropDown_dbValueChanged(event);
        end

        % Value changed function: WorkflowDropDown
        function WorkflowDropDownValueChanged(app, event)
            % Inputs Table Rows Depend On Workflow; Results Are Per Workflow (Saved Simulation Of This Workflow: sync_saved_sim).
            % Constants Table: Uncertainty Rows Marked Unused By DET
            app.set_input_table();
            app.populate_constants_table();
            app.populateResultsTab();
            app.sync_saved_sim(true);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Run In Progress Until This Callback Exits (Project Tree Rename Refused; It Could Run At A Queued-Event Point)
            app.set_run_active(true);
            run_flag = onCleanup(@() app.set_run_active(false));
            prog = []; % App-Scoped Progress Dialog: Locks The CHESS Window Only (Other MATLAB Windows Stay Usable)
            % Slot Files This Run Backed Up (backup_sim_files): Restored If The Run Fails, Dropped Once The Results Are Saved
            backed = [];
            try
                % Backups Left By A Run That Never Finished: Previous Run Back First
                [~, ~, ~, ~, out_files] = app.get_config_path();
                bk = app.sim_backup_paths(out_files);
                app.restore_leftover_backups(bk);
                [pse_name, alt_name] = app.get_active_names();
                %%%% ------- 1. Pull Simulation Inputs ---------
                dbObj = app.wProj.get_project_field('stormDB_list', app.StormDBDropDown_run.Value, '', 'db');
                db_indx = app.wProj.db_indx;
                pseObj = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
                % Every Run Needs An Offshore Elevation (Storm DB Study Grid, Else The PSE Geometry): Checked Before Any Dialog Or
                % Backup, So A Failed Check Touches Nothing (Library check_offshore_elevation, Error Shown In The Run-Failed Dialog)
                check_offshore_elevation(app.create_resp_config(pseObj, dbObj, false));
                % Saved Simulation For This Alternative / Workflow / Storm DB: Run Dialog First. No Saved Simulation: Coldstart
                plan = struct('level', 'cold', 'reason', '', 'old', []);
                if app.saved_sim()
                    [choice, plan] = app.run_start_choice(pseObj, dbObj);
                    switch choice
                        case 'Coldstart'
                            plan.level = 'cold';
                        case 'Load existing'
                            app.load_saved_sim();
                            return
                        case 'Cancel'
                            return
                    end
                end
                S = load(dbObj.output_paths.storm_db, 'storm', 'prob_mass');
                % LCS, Full Poisson Sampling: Convergence Dialog (May Update mcs_nLC) Before The Config Is Built, Only When
                % Life Cycles Are Sampled (A Hotstart Reuses The Cached Forcing Or This Case's Sampling)
                if app.WorkflowDropDown.Value == 3 && strcmp(plan.level, 'cold') && ~app.lcs_convergence_confirm(pseObj, dbObj, S.prob_mass)
                    return
                end
                backed = app.backup_sim_files(bk, plan.level);
                [config, pseObj] = create_resp_config(app, pseObj, dbObj, true, plan); % Includes Hotstart Actions

                %%%% ------ 2. Project Forcing (Library: chess_pros_formater / chess_lcs)
                step_str = 'Creating Replicates';
                unc_str = 'Applying Uncertainty';
                if config.workflow == 3
                    step_str = 'Creating Life Cycles';
                elseif config.workflow == 2 % Deterministic: Storms As Recorded, No Uncertainty
                    step_str = 'Formatting Storms';
                    unc_str = 'No Uncertainty (Deterministic)';
                end
                prog = uiprogressdlg(app.UIFigure, 'Title', 'CHESS Simulation Progress', 'Message', step_str, 'Value', 1/5);
                [project_forcing, config] = call_project_forcing_formater(config, S.storm, S.prob_mass);

                %%%% ------ 3. Add Uncertainty (Library: apply_uncertainty)
                set(prog, 'Value', 2/5, 'Message', unc_str);
                [project_forcing, config] = call_uncertainty_engine(config, project_forcing);

                %%%% ----- 4. Add Forcing Adjustments (Library: adjust_project_forcing)
                set(prog, 'Value', 3/5, 'Message', 'Adjust Project Forcing');
                [project_forcing, config] = call_project_forcing_adjuster(config, project_forcing, pseObj);

                %%%% ---- 5. Run CHESS Simulation
                set(prog, 'Value', 4/5, 'Message', 'Computing Project Responses');
                call_project_response(config, project_forcing, pseObj);
                % Results Saved (Written Once, At The End Of call_project_response): Previous Run's Files No Longer Needed
                for kk = 1:numel(backed)
                    if backed(kk).existed && isfile(backed(kk).prev)
                        delete(backed(kk).prev);
                    end
                end
                backed = [];

                %%%% ---- 6. Store Outputs On Project
                set(prog, 'Value', 4.99/5, 'Message', 'Saving Outputs');
                app.wProj.set_project_field('stormDB_assignment', db_indx, pse_name, alt_name, 'alt');
                app.wProj.set_project_field('project_response_files', config.out_files.resp_data, pse_name, alt_name, 'alt');
                app.save_project();
                set(prog, 'Value', 1, 'Message', 'Done');
                close(prog);
            catch ME
                if ~isempty(prog) && isvalid(prog)
                    close(prog);
                end
                % Failed Before The Results Were Saved: Slot Back To The Previous Run (Config, Forcing, LCS Seed Matching The Results On
                % Disk). Best Effort: A Restore Failure Is Reported With, Not Instead Of, The Run Error
                failed = app.restore_sim_files(backed);
                msg = ['CHESS run failed: ' ME.message];
                if ~isempty(failed)
                    msg = [msg newline newline 'Previous run files not restored: ' strjoin(failed, '; ')];
                end
                app.auxLib.error_dlgbox(msg);
                return
            end
            % Show Results (Of The Current Key); Status Bar: How The Forcing Was Obtained
            app.simKey = app.sim_key();
            app.declinedSimKey = '';
            app.populateResultsTab();
            switch plan.level
                case 'full'
                    app.set_sim_status('Hotstart (full): reused cached forcing.');
                case 'reuse_sampling'
                    if config.workflow == 3
                        app.set_sim_status(['Hotstart: same life cycles, forcing rebuilt (' plan.reason ').']);
                    else
                        app.set_sim_status(['Hotstart: forcing rebuilt (' plan.reason ').']);
                    end
                otherwise
                    app.set_sim_status('Coldstart: forcing regenerated.');
            end
            app.TabGroup.SelectedTab = app.ResultsViewerTab;
        end

        % Callback function: DataTypesTree, ResponsesTree
        function TreeCheckedNodesChanged(app, event)
            % Light Update: Inputs Table Rows & Run Button
            app.set_input_table();
        end

        % Value changed function: ApplyDepthLimitationCheckBox, 
        % ...and 4 other components
        function StormDBDropDown_runValueChanged(app, event)
            % Storm DB Change Rebuilds Data Types; Other Controls Only Need A Light Update
            if event.Source == app.StormDBDropDown_run
                app.populateSimulationTab();
                app.populate_pse_table();
                app.populate_constants_table();
                app.populateResultsTab();
                app.sync_saved_sim(true); % Saved Simulation Of This Storm DB
            elseif app.has_active_alt() % Without An Active Alternative There Are No Inputs To Refresh
                app.set_input_table();
            end
        end

        % Cell edit callback: WorkflowInputsTable
        function WorkflowInputsTableCellEdit(app, event)
            % [NEW] Only The Value Column Is Editable
            row = event.Indices(1);
            col = event.Indices(2);
            if col ~= 3
                app.WorkflowInputsTable.Data{row, col} = event.PreviousData;
                return
            end
            symbol = app.WorkflowInputsTable.Data{row, 1};
            val = event.NewData;
            if strcmp(symbol, 'csr_apply_structure_repair') && ~(isnumeric(val) && isscalar(val) && ismember(val, 0:3))
                app.WorkflowInputsTable.Data{row, col} = event.PreviousData;
                uialert(app.UIFigure, 'Structure Repair must be 0 (None), 1 (Above SLS), 2 (Above ULS) or 3 (Above SLS Or ULS).',...
                    'Inputs: Invalid Value', 'Icon', 'warning');
                return
            end
            app.tableState.(symbol) = val;
            % DPA Settings Rows Follow csr_call_dpa
            if strcmp(symbol, 'csr_call_dpa')
                app.set_input_table();
            end
        end

        % Menu selected function: RemoveStormDatabaseMenu
        function RemoveStormDatabaseMenuSelected(app, event)
            % Remove A Storm Database From The Active Project. Tied To Simulations (Run Files Or Linked Alternatives): Deleted
            % With Its Files And Those Run Files. Otherwise: Remove (Files Kept) Or Remove & Delete Files
            if isempty(app.wProj) || isempty(app.wProj.stormDB_list)
                return
            end
            % Not While A Run Writes Into The Project Folders
            if app.runActive
                uialert(app.UIFigure, 'Removing is not available while a run is in progress.', 'Storm Database Management: Remove', 'Icon', 'info');
                return
            end
            app.auxLib.dropdown_input_dlgbox(['Remove storm database from project ' app.wProj.name '.'],...
                {app.wProj.stormDB_list.db_name}, 'Storm Database Management: Remove', 'Remove');
            db_name = app.auxLib.dialog_answer;
            if isempty(db_name)
                return
            end
            % Storm DB Files (storm/prob_mass + Raw CHS_Data, If Any)
            dbObj = app.wProj.get_project_field('stormDB_list', db_name, '', 'db');
            db_files = {};
            for ff = {'storm_db', 'chs_data'}
                if isfield(dbObj.output_paths, ff{1}) && isfile(dbObj.output_paths.(ff{1}))
                    db_files{end+1} = dbObj.output_paths.(ff{1}); %#ok<AGROW>
                end
            end
            tied = app.wProj.stormdb_tied_sims(db_name);
            if ~isempty(tied)
                % Tied Simulations: Only Delete (Results Of A Removed Storm DB Could Not Be Shown Or Rerun)
                n_max = 15;
                lines = strcat({['  ' char(8226) ' ']}, tied);
                if numel(lines) > n_max
                    lines = [lines(1:n_max-1); {sprintf('  %sand %d more', char(8230), numel(tied) - n_max + 1)}];
                end
                msg = sprintf('Storm DB "%s" is tied to the following simulations:\n%s\n\nDeleting the storm DB will remove these alternatives'' simulation results.',...
                    db_name, strjoin(lines, newline));
                app.auxLib.choice_dlgbox(msg, {'Delete', 'Cancel'}, 'Remove Storm Database', 560); % Last Button = Default (Enter): Cancel
                answer = app.auxLib.dialog_answer;
                if ~strcmp(answer, 'Delete') % Cancel Or Window Closed
                    return
                end
            else
                msg = sprintf('Remove storm database "%s"?', db_name);
                options = {'Remove', 'Cancel'};
                if ~isempty(db_files)
                    msg = sprintf('%s\n"Remove" keeps its files in Event_Database; "Remove & Delete Files" deletes them.', msg);
                    options = {'Remove', 'Remove & Delete Files', 'Cancel'};
                end
                app.auxLib.choice_dlgbox(msg, options, 'Remove Storm Database');
                answer = app.auxLib.dialog_answer;
                if isempty(answer) || strcmp(answer, 'Cancel') % Cancel Or Window Closed
                    return
                end
            end
            % Drop Cached storm / CHS_Data Of This Database
            keys = app.stormCache.keys();
            stale = keys(ismember(lower(keys), lower(db_files)));
            if ~isempty(stale)
                remove(app.stormCache, stale);
            end
            failed = {};
            switch answer
                case 'Delete'
                    % Run Files Of Its Simulations And The Storm DB Files Deleted; Session State Of The DB Dropped
                    run_files = app.wProj.stormdb_run_files(db_name);
                    app.wProj.remove_stormdb(db_name);
                    for ff = [run_files; db_files']'
                        delete(ff{1});
                        if isfile(ff{1})
                            failed{end+1} = ff{1}; %#ok<AGROW>
                        end
                    end
                    app.oldDbWarned = app.oldDbWarned(~ismember(lower(app.oldDbWarned), lower(db_files)));
                    app.simKey = [];
                    app.declinedSimKey = '';
                    app.resultsState = [];
                case 'Remove & Delete Files'
                    app.wProj.remove_stormdb(db_name);
                    cellfun(@delete, db_files);
                otherwise
                    app.wProj.remove_stormdb(db_name);
            end
            % Last CHS (Non-Custom) Storm DB Removed: The Project Has No Region, Its CHS_Dependencies Is Free Again
            if isempty(app.wProj.project_region())
                app.wProj.chs_dependencies = '';
            end
            % Refresh & Save
            app.set_active_selection(app.wProj.name, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            app.refresh_active_view();
            app.save_project();
            if ~isempty(failed)
                uialert(app.UIFigure, sprintf('Storm DB "%s" removed, but these files could not be deleted:\n%s',...
                    db_name, strjoin(failed, newline)), 'Remove Storm Database', 'Icon', 'warning');
            end
        end

        % Menu selected function: TidesMenu
        function TidesMenuSelected(app, event)
            % Download A NOAA Tidal Prediction File Into The Project (Tides UI), Then Refresh The Run Tab
            if isempty(app.wProj)
                return
            end
            tidesUI = NOAATidesPredictionsUI(app.wProj, app.UIFigure);
            try % Wait Until The Tides UI Closes (It Closes Itself After A Successful Download)
                waitfor(tidesUI.UIFigure);
            catch % Already Closed
            end
            % Refresh & Save
            app.set_active_selection(app.wProj.name, app.PSEDropDown.Value, app.AlternativeDropDown.Value);
            app.refresh_active_view();
            app.save_project();
        end

        % Cell edit callback: PSETable
        function PSETableCellEdit(app, event)
            % [NEW] PSE Viewer: Edit A Value Of The Active PSE/Alternative (Rows Are Added/Removed In The PSE Creation Dialog Only)
            row = event.Indices(1);
            col = event.Indices(2);
            if col ~= 4 || ~app.has_active_alt()
                app.PSETable.Data{row, col} = event.PreviousData;
                return
            end
            val = event.NewData;
            if ~(isnumeric(val) && isscalar(val) && isreal(val) && isfinite(val))
                app.PSETable.Data{row, col} = event.PreviousData;
                uialert(app.UIFigure, sprintf('%s must be a finite number.', app.PSETable.Data{row, 2}),...
                    'PSE Viewer: Invalid Value', 'Icon', 'warning');
                return
            end
            [pse_name, alt_name] = app.get_active_names();
            old_tbl = app.wProj.get_project_field('pse_tables', pse_name, alt_name, 'alt');
            geo = app.wProj.get_project_field('pse_geometry', pse_name, alt_name, 'alt');
            % Rebuild The Geometry (Library Rules); A Library Error Restores The Previous Table (pse_geometry Is Only Set On Success)
            symbol = app.PSETable.Data{row, 1};
            new_tbl = old_tbl;
            new_tbl{strcmp(old_tbl(:, 1), symbol), 4} = val;
            app.wProj.set_project_field('pse_tables', new_tbl, pse_name, alt_name, 'alt');
            try
                app.wProj.create_geometry(pse_name, alt_name, geo.pse_type);
            catch ME
                app.PSETable.Data{row, col} = event.PreviousData;
                app.wProj.set_project_field('pse_tables', old_tbl, pse_name, alt_name, 'alt');
                uialert(app.UIFigure, ['Could not rebuild the PSE geometry: ' ME.message], 'PSE Viewer: Invalid Value');
                return
            end
            app.wProj.plot_geometry(pse_name, alt_name, app.PSEAxes, app.plotFontSize);
            app.save_project();
            % Constants Table (Water Density, Resolved Offshore Elevation) And Status Bar (PSE Geometry Vs Loaded Results). Same Key: No sync_saved_sim
            app.populate_constants_table();
            if ~isempty(app.resultsState)
                app.set_sim_status(app.results_status());
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectManagementMenu = uimenu(app.UIFigure);
            app.ProjectManagementMenu.Text = 'Project Management';
            app.CreateNewProjectMenu = uimenu(app.ProjectManagementMenu);
            app.CreateNewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateNewProjectMenuSelected, true);
            app.CreateNewProjectMenu.Text = 'Create New Project';
            app.ImportExportProjectMenu = uimenu(app.ProjectManagementMenu);
            app.ImportExportProjectMenu.Text = 'Import/Export Project';
            app.ImportProjectMenu = uimenu(app.ImportExportProjectMenu);
            app.ImportProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.ImportProjectMenu.Text = 'Import Project';
            app.ExportProjectMenu = uimenu(app.ImportExportProjectMenu);
            app.ExportProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportProjectMenuSelected, true);
            app.ExportProjectMenu.Text = 'Export Project';
            app.AddProjectElementMenu = uimenu(app.ProjectManagementMenu);
            app.AddProjectElementMenu.Text = 'Add Project Element';
            app.PSEMenu = uimenu(app.AddProjectElementMenu);
            app.PSEMenu.Text = 'PSE';
            app.LeveeMenu = uimenu(app.PSEMenu);
            app.LeveeMenu.MenuSelectedFcn = createCallbackFcn(app, @PSECreationMenuSelected, true);
            app.LeveeMenu.Text = 'Levee';
            app.RubbleMoundMenu = uimenu(app.PSEMenu);
            app.RubbleMoundMenu.MenuSelectedFcn = createCallbackFcn(app, @PSECreationMenuSelected, true);
            app.RubbleMoundMenu.Text = 'Rubble Mound';
            app.FloodwallMenu = uimenu(app.PSEMenu);
            app.FloodwallMenu.MenuSelectedFcn = createCallbackFcn(app, @PSECreationMenuSelected, true);
            app.FloodwallMenu.Text = 'Floodwall';
            app.LowCrestedBreakwaterMenu = uimenu(app.PSEMenu);
            app.LowCrestedBreakwaterMenu.MenuSelectedFcn = createCallbackFcn(app, @PSECreationMenuSelected, true);
            app.LowCrestedBreakwaterMenu.Text = 'Low Crested Breakwater';
            app.AlternativeMenu = uimenu(app.AddProjectElementMenu);
            app.AlternativeMenu.MenuSelectedFcn = createCallbackFcn(app, @AlternativeMenuSelected, true);
            app.AlternativeMenu.Text = 'Alternative';
            app.TidesMenu = uimenu(app.AddProjectElementMenu);
            app.TidesMenu.MenuSelectedFcn = createCallbackFcn(app, @TidesMenuSelected, true);
            app.TidesMenu.Text = 'Tides';
            app.RemoveProjectElementMenu = uimenu(app.ProjectManagementMenu);
            app.RemoveProjectElementMenu.Text = 'Remove Project Element';
            app.RemovePSEMenu = uimenu(app.RemoveProjectElementMenu);
            app.RemovePSEMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveProjectElem, true);
            app.RemovePSEMenu.Text = 'PSE';
            app.RemoveAlternativeMenu = uimenu(app.RemoveProjectElementMenu);
            app.RemoveAlternativeMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveProjectElem, true);
            app.RemoveAlternativeMenu.Text = 'Alternative';
            app.RemoveTidesMenu = uimenu(app.RemoveProjectElementMenu);
            app.RemoveTidesMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveProjectElem, true);
            app.RemoveTidesMenu.Text = 'Tides';
            app.RemoveAlternativeSimulationMenu = uimenu(app.ProjectManagementMenu);
            app.RemoveAlternativeSimulationMenu.Text = 'Remove Alternative Simulation';
            app.StormDatabaseManagementMenu = uimenu(app.UIFigure);
            app.StormDatabaseManagementMenu.Text = 'Storm Database Management';
            app.AddStormDatabaseMenu = uimenu(app.StormDatabaseManagementMenu);
            app.AddStormDatabaseMenu.Text = 'Add Storm Database';
            app.ImportCHSStudyHDF5Menu = uimenu(app.AddStormDatabaseMenu);
            app.ImportCHSStudyHDF5Menu.MenuSelectedFcn = createCallbackFcn(app, @CHSDatabaseMenu, true);
            app.ImportCHSStudyHDF5Menu.Text = 'Import CHS Study (HDF5)';
            app.RemoveStormDatabaseMenu = uimenu(app.StormDatabaseManagementMenu);
            app.RemoveStormDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveStormDatabaseMenuSelected, true);
            app.RemoveStormDatabaseMenu.Text = 'Remove Storm Database';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ParentPanel = uipanel(app.GridLayout);
            app.MasterGrid = uigridlayout(app.ParentPanel);
            app.ProjectPanelGrid = uigridlayout(app.MasterGrid);
            app.ProjectTreeLabel = uilabel(app.ProjectPanelGrid);
            app.ProjectTreeLabel.Text = 'Project Tree';
            app.ProjectTree = uitree(app.ProjectPanelGrid);
            app.ProjectTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectTree_SelectionChanged, true);
            app.ProjectNavigationLabel = uilabel(app.ProjectPanelGrid);
            app.ProjectNavigationLabel.Text = 'Project Navigation:';
            app.ProjectPanel = uipanel(app.ProjectPanelGrid);
            app.GridLayout8 = uigridlayout(app.ProjectPanel);
            app.ProjectLabel = uilabel(app.GridLayout8);
            app.ProjectLabel.Text = 'Project:';
            app.ProjectDropDown = uidropdown(app.GridLayout8);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = {};
            app.PSEDropDownLabel = uilabel(app.GridLayout8);
            app.PSEDropDownLabel.Text = 'PSE:';
            app.PSEDropDown = uidropdown(app.GridLayout8);
            app.PSEDropDown.Items = {};
            app.PSEDropDown.ValueChangedFcn = createCallbackFcn(app, @PSEDropDownValueChanged, true);
            app.PSEDropDown.Value = {};
            app.AlternativeDropDownLabel = uilabel(app.GridLayout8);
            app.AlternativeDropDownLabel.Text = 'Alternative:';
            app.AlternativeDropDown = uidropdown(app.GridLayout8);
            app.AlternativeDropDown.Items = {};
            app.AlternativeDropDown.ValueChangedFcn = createCallbackFcn(app, @AlternativeDropDownValueChanged, true);
            app.AlternativeDropDown.Value = {};
            app.ProjectPathEditFieldLabel = uilabel(app.GridLayout8);
            app.ProjectPathEditFieldLabel.Text = 'Project Path:';
            app.ProjectPathEditField = uieditfield(app.GridLayout8, 'text');
            app.ProjectPathEditField.Editable = 'off';
            app.ConstantsLabel = uilabel(app.ProjectPanelGrid);
            app.ConstantsLabel.Text = 'Constants';
            app.Image = uiimage(app.ProjectPanelGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SS_comb_logo.png');
            app.ConstantsTable = uitable(app.ProjectPanelGrid);
            app.ConstantsTable.ColumnName = {'Field'; 'Units'; 'Value'};
            app.ConstantsTable.RowName = {};
            app.TabGroup = uitabgroup(app.MasterGrid);
            app.PSEViewerTab = uitab(app.TabGroup);
            app.PSEViewerTab.Title = 'PSE Viewer';
            app.PSEViewerGrid = uigridlayout(app.PSEViewerTab);
            app.PSEAxes = uiaxes(app.PSEViewerGrid);
            xlabel(app.PSEAxes, 'X Distance')
            ylabel(app.PSEAxes, 'Elevation')
            app.PSEButtonGrid = uigridlayout(app.PSEViewerGrid);
            app.ExtrudeTableButton = uibutton(app.PSEButtonGrid, 'push');
            app.ExtrudeTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeTableButtonPushed, true);
            app.ExtrudeTableButton.Icon = fullfile(pathToMLAPP, 'table_icon.png');
            app.ExtrudeTableButton.Text = '';
            app.ExtrudeFigureButton = uibutton(app.PSEButtonGrid, 'push');
            app.ExtrudeFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeFigureButtonPushed, true);
            app.ExtrudeFigureButton.Icon = fullfile(pathToMLAPP, 'plot_icon.png');
            app.ExtrudeFigureButton.Text = '';
            app.PSETable = uitable(app.PSEViewerGrid);
            app.PSETable.ColumnName = {'model_var'; 'Description'; 'Units'; 'Value'};
            app.PSETable.RowName = {};
            app.PSETable.ColumnSortable = [false true false false];
            app.PSETable.ColumnEditable = [false false false true];
            app.PSETable.CellEditCallback = createCallbackFcn(app, @PSETableCellEdit, true);
            app.StormDBViewerTab = uitab(app.TabGroup);
            app.StormDBViewerTab.Title = 'Storm DB Viewer';
            app.StormDBViewerGrid = uigridlayout(app.StormDBViewerTab);
            app.StormDBAxes = uiaxes(app.StormDBViewerGrid);
            xlabel(app.StormDBAxes, 'X Distance')
            ylabel(app.StormDBAxes, 'Elevation')
            app.StormDBViewerButtonGrid = uigridlayout(app.StormDBViewerGrid);
            app.ExtrudeTableButton_2 = uibutton(app.StormDBViewerButtonGrid, 'push');
            app.ExtrudeTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeTableButtonPushed, true);
            app.ExtrudeTableButton_2.Icon = fullfile(pathToMLAPP, 'table_icon.png');
            app.ExtrudeTableButton_2.Text = '';
            app.ExtrudeFigureButton_2 = uibutton(app.StormDBViewerButtonGrid, 'push');
            app.ExtrudeFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeFigureButtonPushed, true);
            app.ExtrudeFigureButton_2.Icon = fullfile(pathToMLAPP, 'plot_icon.png');
            app.ExtrudeFigureButton_2.Text = '';
            app.StormDBViewerSplitGrid = uigridlayout(app.StormDBViewerGrid);
            app.Panel = uipanel(app.StormDBViewerSplitGrid);
            app.StormDBViewerNavigationPanelGrid = uigridlayout(app.Panel);
            app.StormIDDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.StormIDDropDownLabel.Text = 'Storm ID:';
            app.StormIDDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.StormIDDropDown.Items = {};
            app.StormIDDropDown.ValueChangedFcn = createCallbackFcn(app, @updateStormDBPlot, true);
            app.StormIDDropDown.Value = {};
            app.FieldToPlotLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.FieldToPlotLabel.Text = 'Field To Plot:';
            app.FieldToPlotDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.FieldToPlotDropDown.Items = {};
            app.FieldToPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @updateStormDBPlot, true);
            app.FieldToPlotDropDown.Value = {};
            app.MatchTypeDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.MatchTypeDropDownLabel.Text = 'Match Type:';
            app.MatchTypeDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.MatchTypeDropDown.Items = {};
            app.MatchTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateStormDBPlot, true);
            app.MatchTypeDropDown.Value = {};
            app.DataTypeDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.DataTypeDropDownLabel.Text = 'Data Type:';
            app.DataTypeDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDown_dbOpening, true);
            app.DataTypeDropDown.Value = {};
            app.StormTypeDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.StormTypeDropDownLabel.Text = 'Storm Type:';
            app.StormTypeDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.StormTypeDropDown.Items = {};
            app.StormTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StormTypeDropDown_dbOpening, true);
            app.StormTypeDropDown.Value = {};
            app.StormDBDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid);
            app.StormDBDropDownLabel.Text = 'Storm DB:';
            app.StormDBDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid);
            app.StormDBDropDown.Items = {};
            app.StormDBDropDown.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_dbValueChanged, true);
            app.StormDBDropDown.Value = {};
            app.StormDBTable = uitable(app.StormDBViewerSplitGrid);
            app.StormDBTable.ColumnName = {'Storm Timestamp'; 'Water Level'};
            app.StormDBTable.RowName = {};
            app.CHESSRunTab = uitab(app.TabGroup);
            app.CHESSRunTab.Title = 'CHESS Run';
            app.RunViewerGrid = uigridlayout(app.CHESSRunTab);
            app.DefineProjectForcingLabel_2 = uilabel(app.RunViewerGrid);
            app.DefineProjectForcingLabel_2.Text = '1. Define Project Forcing';
            app.DefineProjectResponsesDataTypesToComputeLabel = uilabel(app.RunViewerGrid);
            app.DefineProjectResponsesDataTypesToComputeLabel.Text = '3. Define Project Responses & Data Types To Compute';
            app.RunSimulationLabel = uilabel(app.RunViewerGrid);
            app.RunSimulationLabel.Text = '4. Run Simulation';
            app.GridLayout3 = uigridlayout(app.RunViewerGrid);
            app.ApplyDepthLimitationCheckBox = uicheckbox(app.GridLayout3);
            app.ApplyDepthLimitationCheckBox.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_runValueChanged, true);
            app.ApplyDepthLimitationCheckBox.Text = 'Apply Depth Limitation';
            app.StormDBDropDown_2Label = uilabel(app.GridLayout3);
            app.StormDBDropDown_2Label.Text = 'Storm  DB:';
            app.StormDBDropDown_run = uidropdown(app.GridLayout3);
            app.StormDBDropDown_run.Items = {};
            app.StormDBDropDown_run.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_runValueChanged, true);
            app.StormDBDropDown_run.Value = {};
            app.GridLayout3_2 = uigridlayout(app.RunViewerGrid);
            app.StormTypeDropDown_2Label_2 = uilabel(app.GridLayout3_2);
            app.StormTypeDropDown_2Label_2.Text = 'Storm Type:';
            app.StormTypeDropDown_2 = uidropdown(app.GridLayout3_2);
            app.StormTypeDropDown_2.Items = {};
            app.StormTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_runValueChanged, true);
            app.StormTypeDropDown_2.Value = {};
            app.SeaLevelChangeEditFieldLabel = uilabel(app.GridLayout3_2);
            app.SeaLevelChangeEditFieldLabel.Text = 'Sea Level Change:';
            app.SeaLevelChangeEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.SeaLevelChangeEditField.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_runValueChanged, true);
            app.GridLayout3_3 = uigridlayout(app.RunViewerGrid);
            app.TideFileDropDownLabel = uilabel(app.GridLayout3_3);
            app.TideFileDropDownLabel.Text = 'Tide File:';
            app.TidesFileDropDown = uidropdown(app.GridLayout3_3);
            app.TidesFileDropDown.Items = {'None'};
            app.TidesFileDropDown.ValueChangedFcn = createCallbackFcn(app, @StormDBDropDown_runValueChanged, true);
            app.TidesFileDropDown.Value = 'None';
            app.GridLayout3_4 = uigridlayout(app.RunViewerGrid);
            app.DefineCHESSWorkflowLabel = uilabel(app.GridLayout3_4);
            app.DefineCHESSWorkflowLabel.Text = '2. Define CHESS Workflow ';
            app.WorkflowDropDown = uidropdown(app.GridLayout3_4);
            app.WorkflowDropDown.Items = {'CHESS: PROS-RB', 'CHESS: Deterministic', 'CHESS:LCS', 'CHESS: PROS-FB'};
            app.WorkflowDropDown.ItemsData = [1 2 3 4];
            app.WorkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @WorkflowDropDownValueChanged, true);
            app.WorkflowDropDown.Value = 1;
            app.WorkflowInputsTable = uitable(app.RunViewerGrid);
            app.WorkflowInputsTable.ColumnName = {'model_var'; 'Field'; 'Value'};
            app.WorkflowInputsTable.RowName = {};
            app.WorkflowInputsTable.ColumnEditable = [false false true];
            app.WorkflowInputsTable.CellEditCallback = createCallbackFcn(app, @WorkflowInputsTableCellEdit, true);
            app.GridLayout3_5 = uigridlayout(app.RunViewerGrid);
            app.PSEResponsesLabel = uilabel(app.GridLayout3_5);
            app.PSEResponsesLabel.Text = 'PSE Responses:';
            app.DataTypesToProcessLabel = uilabel(app.GridLayout3_5);
            app.DataTypesToProcessLabel.Text = 'Data Types To Process:';
            app.GridLayout3_6 = uigridlayout(app.RunViewerGrid);
            app.ResponsesTree = uitree(app.GridLayout3_6, 'checkbox');
            % Assign Checked Nodes
            app.ResponsesTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.DataTypesTree = uitree(app.GridLayout3_6, 'checkbox');
            % Assign Checked Nodes
            app.DataTypesTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.GridLayout3_7 = uigridlayout(app.RunViewerGrid);
            app.RunSimulationButton = uibutton(app.GridLayout3_7, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Enable = 'off';
            app.RunSimulationButton.Text = 'Run Simulation';
            app.simStatusBar = uitextarea(app.GridLayout3_7);
            app.simStatusBar.Editable = 'off';
            app.ResultsViewerTab = uitab(app.TabGroup);
            app.ResultsViewerTab.Title = 'Results Viewer';
            app.StormDBViewerGrid_2 = uigridlayout(app.ResultsViewerTab);
            app.PSEAxes_3 = uiaxes(app.StormDBViewerGrid_2);
            xlabel(app.PSEAxes_3, 'X Distance')
            ylabel(app.PSEAxes_3, 'Elevation')
            app.StormDBViewerButtonGrid_2 = uigridlayout(app.StormDBViewerGrid_2);
            app.Button_5 = uibutton(app.StormDBViewerButtonGrid_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeTableButtonPushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'table_icon.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.StormDBViewerButtonGrid_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ExtrudeFigureButtonPushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'plot_icon.png');
            app.Button_6.Text = '';
            app.StormDBViewerSplitGrid_2 = uigridlayout(app.StormDBViewerGrid_2);
            app.Panel_2 = uipanel(app.StormDBViewerSplitGrid_2);
            app.StormDBViewerNavigationPanelGrid_2 = uigridlayout(app.Panel_2);
            app.PlotTypeDropDownLabel = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.PlotTypeDropDownLabel.Text = 'Plot Type:';
            app.PlotTypeDropDown = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.PlotTypeDropDown.Items = {};
            app.PlotTypeDropDown.Value = {};
            app.StormTypeDropDown_2Label = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.StormTypeDropDown_2Label.Text = 'Storm Type:';
            app.StormTypeDropDown_results = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.StormTypeDropDown_results.Items = {};
            app.StormTypeDropDown_results.Value = {};
            app.DataTypeDropDown_2Label = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.DataTypeDropDown_2Label.Text = 'Data Type:';
            app.DataTypeDropDown_results = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.DataTypeDropDown_results.Items = {};
            app.DataTypeDropDown_results.Value = {};
            app.MatchTypeDropDown_2Label = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.MatchTypeDropDown_2Label.Text = 'Match Type:';
            app.MatchTypeDropDown_results = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.MatchTypeDropDown_results.Items = {};
            app.MatchTypeDropDown_results.Value = {};
            app.FieldToPlotDropDown_2Label = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.FieldToPlotDropDown_2Label.Text = 'Field To Plot:';
            app.FieldToPlotDropDown_results = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.FieldToPlotDropDown_results.Items = {};
            app.FieldToPlotDropDown_results.Value = {};
            app.StormIDDropDown_2Label = uilabel(app.StormDBViewerNavigationPanelGrid_2);
            app.StormIDDropDown_2Label.Text = 'Storm ID:';
            app.StormIDDropDown_results = uidropdown(app.StormDBViewerNavigationPanelGrid_2);
            app.StormIDDropDown_results.Items = {};
            app.StormIDDropDown_results.Value = {};
            app.PSETable_3 = uitable(app.StormDBViewerSplitGrid_2);
            app.PSETable_3.ColumnName = {'Storm Timestamp'; 'Water Level'};
            app.PSETable_3.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CHESS_UI_canvas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end